classdef Diffusion_Calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DiffusonCalculatorUIFigure     matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        CalculatorTab                  matlab.ui.container.Tab
        BValueTree                     matlab.ui.container.CheckBoxTree
        Node                           matlab.ui.container.TreeNode
        Node2                          matlab.ui.container.TreeNode
        Node3                          matlab.ui.container.TreeNode
        Node4                          matlab.ui.container.TreeNode
        CopyDataButton                 matlab.ui.control.Button
        MedullaROIFromSegmenterButton  matlab.ui.control.Button
        CortexROIFromSegmenterButton   matlab.ui.control.Button
        LocationEditField              matlab.ui.control.NumericEditField
        LocationEditFieldLabel         matlab.ui.control.Label
        SmoothingDegreeEditField       matlab.ui.control.NumericEditField
        SmoothingDegreeEditFieldLabel  matlab.ui.control.Label
        mapRoiSwitch                   matlab.ui.control.Switch
        StateField                     matlab.ui.control.EditField
        KernelSizeEditField            matlab.ui.control.NumericEditField
        KernelSizeEditFieldLabel       matlab.ui.control.Label
        SliceKnob                      matlab.ui.control.DiscreteKnob
        SliceKnobLabel                 matlab.ui.control.Label
        BValueKnob                     matlab.ui.control.DiscreteKnob
        BValueKnobLabel                matlab.ui.control.Label
        GenerateMapsButton             matlab.ui.control.Button
        EditField_2                    matlab.ui.control.NumericEditField
        EditField                      matlab.ui.control.NumericEditField
        AlgorithmButtonGroup           matlab.ui.container.ButtonGroup
        TriExpButton                   matlab.ui.control.RadioButton
        BayesianButton                 matlab.ui.control.RadioButton
        BiExpSegButton                 matlab.ui.control.RadioButton
        BiexpFreeButton                matlab.ui.control.RadioButton
        MonoExpButton                  matlab.ui.control.RadioButton
        LoadDICOMButton                matlab.ui.control.Button
        OptionsPanel                   matlab.ui.container.Panel
        UndoFilterButton               matlab.ui.control.Button
        FilterDWIBetaButton            matlab.ui.control.Button
        pFslowEditField                matlab.ui.control.NumericEditField
        pFslowEditFieldLabel           matlab.ui.control.Label
        pFinterEditField               matlab.ui.control.NumericEditField
        pFinterEditFieldLabel          matlab.ui.control.Label
        pFfastEditField                matlab.ui.control.NumericEditField
        pFfastEditFieldLabel           matlab.ui.control.Label
        ImportFromLastDatabaseButton   matlab.ui.control.Button
        DslowEditField                 matlab.ui.control.NumericEditField
        DslowEditField_5Label          matlab.ui.control.Label
        DinterEditField                matlab.ui.control.NumericEditField
        DinterEditFieldLabel           matlab.ui.control.Label
        DfastEditField                 matlab.ui.control.NumericEditField
        DfastEditFieldLabel            matlab.ui.control.Label
        MultiRoiSwitch                 matlab.ui.control.Switch
        DrawMultipleROIButton          matlab.ui.control.Button
        DrawROIButton                  matlab.ui.control.StateButton
        ROIStyleDropDown               matlab.ui.control.DropDown
        ROIStyleDropDownLabel          matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        ADCEditField                   matlab.ui.control.NumericEditField
        ADCEditFieldLabel              matlab.ui.control.Label
        pFEditField                    matlab.ui.control.NumericEditField
        pFEditFieldLabel               matlab.ui.control.Label
        DpEditField                    matlab.ui.control.NumericEditField
        DpEditFieldLabel               matlab.ui.control.Label
        DtEditField                    matlab.ui.control.NumericEditField
        DtEditFieldLabel               matlab.ui.control.Label
        ImageViewPanel                 matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        FittingGraphicsPanel           matlab.ui.container.Panel
        RSquareEditField               matlab.ui.control.NumericEditField
        RSquareEditFieldLabel          matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        DicomTab                       matlab.ui.container.Tab
        ImageNumberSpinner             matlab.ui.control.Spinner
        ImageNumberSpinnerLabel        matlab.ui.control.Label
        DicomState                     matlab.ui.control.EditField
        PatientsPanel                  matlab.ui.container.Panel
        PatientsListBox                matlab.ui.control.ListBox
        PatientsListBoxLabel           matlab.ui.control.Label
        SequencesPanel                 matlab.ui.container.Panel
        LoadDICOMDatabaseButton        matlab.ui.control.Button
        SaveDICOMDatabaseButton        matlab.ui.control.Button
        TimeEditField                  matlab.ui.control.EditField
        TimeEditFieldLabel             matlab.ui.control.Label
        LoadtoBOLDPcsButton            matlab.ui.control.Button
        FramesEditField                matlab.ui.control.NumericEditField
        FramesEditFieldLabel           matlab.ui.control.Label
        LoadtoViewerButton             matlab.ui.control.Button
        LoadtoCalculatorButton         matlab.ui.control.Button
        SequencesListBox               matlab.ui.control.ListBox
        SequencesListBoxLabel          matlab.ui.control.Label
        PreviewAxes                    matlab.ui.control.UIAxes
        ViewerTab                      matlab.ui.container.Tab
        ImageViewPanel_2               matlab.ui.container.Panel
        BritghtnessSlider              matlab.ui.control.Slider
        BritghtnessSliderLabel         matlab.ui.control.Label
        ContrastSlider                 matlab.ui.control.RangeSlider
        ContrastSliderLabel            matlab.ui.control.Label
        ColormapDropDown               matlab.ui.control.DropDown
        ColormapDropDownLabel          matlab.ui.control.Label
        InstanceNumberEditField        matlab.ui.control.NumericEditField
        InstanceNumberEditFieldLabel   matlab.ui.control.Label
        ViewSlider                     matlab.ui.control.Slider
        ViewSliderLabel                matlab.ui.control.Label
        UIAxes8                        matlab.ui.control.UIAxes
        MapsTab                        matlab.ui.container.Tab
        TriExpMapsPanel                matlab.ui.container.Panel
        ColorMapsDropDown              matlab.ui.control.DropDown
        ColorMapsDropDownLabel         matlab.ui.control.Label
        SaveMapsButton                 matlab.ui.control.Button
        UIAxesf3                       matlab.ui.control.UIAxes
        UIAxesf2                       matlab.ui.control.UIAxes
        UIAxesf1                       matlab.ui.control.UIAxes
        UIAxesD3                       matlab.ui.control.UIAxes
        UIAxesD2                       matlab.ui.control.UIAxes
        UIAxesD1                       matlab.ui.control.UIAxes
        DWIMAPSPanel                   matlab.ui.container.Panel
        MethodDropDown                 matlab.ui.control.DropDown
        MethodLabel                    matlab.ui.control.Label
        UIAxes3_f                      matlab.ui.control.UIAxes
        UIAxes3_dt                     matlab.ui.control.UIAxes
        UIAxes_dp                      matlab.ui.control.UIAxes
        BOLD_Proceesor                 matlab.ui.container.Tab
        DrawROIforFitButton            matlab.ui.control.StateButton
        BoldState                      matlab.ui.control.EditField
        GenerateR2MapButton            matlab.ui.control.Button
        BoldSliceKnob                  matlab.ui.control.DiscreteKnob
        BoldSliceLabel                 matlab.ui.control.Label
        TEKnob                         matlab.ui.control.DiscreteKnob
        TEKnobLabel                    matlab.ui.control.Label
        R2MapandFittingPanel           matlab.ui.container.Panel
        MeanR2msec1EditField           matlab.ui.control.NumericEditField
        MeanR2msec1EditFieldLabel      matlab.ui.control.Label
        UIAxes12                       matlab.ui.control.UIAxes
        UIAxes11                       matlab.ui.control.UIAxes
        R2ImagesPanel                  matlab.ui.container.Panel
        UIAxes10                       matlab.ui.control.UIAxes
        RegisterTab                    matlab.ui.container.Tab
        Panel                          matlab.ui.container.Panel
        SegmentGuidedRegisterButton    matlab.ui.control.Button
        MovingSegmentButton            matlab.ui.control.Button
        FixedSegmentButton             matlab.ui.control.Button
        DrawROIButton_3                matlab.ui.control.Button
        DrawROIButton_2                matlab.ui.control.Button
        RegisterStatusEditField        matlab.ui.control.EditField
        StatusEditFieldLabel           matlab.ui.control.Label
        FilterButton                   matlab.ui.control.Button
        CoveredDepthEditField_2        matlab.ui.control.NumericEditField
        CoveredDepthEditField_2Label   matlab.ui.control.Label
        CoveredDepthEditField          matlab.ui.control.NumericEditField
        CoveredDepthEditFieldLabel     matlab.ui.control.Label
        LocationEditField_3            matlab.ui.control.NumericEditField
        LocationEditField_3Label       matlab.ui.control.Label
        LocationEditField_2            matlab.ui.control.NumericEditField
        LocationEditField_2Label       matlab.ui.control.Label
        ShowPairButton                 matlab.ui.control.Button
        LoadButton_2                   matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
        SendRegisteredImagetoSegmenterButton  matlab.ui.control.Button
        RegisterButton                 matlab.ui.control.Button
        MovingSliceSlider              matlab.ui.control.Slider
        MovingSliceSliderLabel         matlab.ui.control.Label
        FixedSliceSlider               matlab.ui.control.Slider
        FixedSliceSliderLabel          matlab.ui.control.Label
        MovingListBox                  matlab.ui.control.ListBox
        MovingListBoxLabel             matlab.ui.control.Label
        MovingImageButton              matlab.ui.control.Button
        FixedImageButton               matlab.ui.control.Button
        FixedListBox                   matlab.ui.control.ListBox
        FixedListBoxLabel              matlab.ui.control.Label
        UIAxes13_3                     matlab.ui.control.UIAxes
        UIAxes13_2                     matlab.ui.control.UIAxes
        UIAxes13                       matlab.ui.control.UIAxes
        SegmenterTab                   matlab.ui.container.Tab
        Segmenter                      matlab.ui.container.Panel
        CalculateSimilarityCoefficientsButton  matlab.ui.control.Button
        DICEMedullaEditField           matlab.ui.control.NumericEditField
        DICEMedullaEditFieldLabel      matlab.ui.control.Label
        JaccardMedullaEditField        matlab.ui.control.NumericEditField
        JaccardMedullaEditFieldLabel   matlab.ui.control.Label
        DICECortexEditField            matlab.ui.control.NumericEditField
        DICECortexEditFieldLabel       matlab.ui.control.Label
        JaccardCortexEditField         matlab.ui.control.NumericEditField
        JaccardCortexEditFieldLabel    matlab.ui.control.Label
        DrawCortexGroundTruthButton    matlab.ui.control.Button
        SegmentButton                  matlab.ui.control.Button
        UIAxes14_2                     matlab.ui.control.UIAxes
        UIAxes14                       matlab.ui.control.UIAxes
        FitParameterSettingsTab        matlab.ui.container.Tab
        TriExpSettings                 matlab.ui.container.Panel
        EditFieldTri_f2                matlab.ui.control.EditField
        EditFieldTri_f1                matlab.ui.control.EditField
        EditFieldTri_Dslow             matlab.ui.control.EditField
        EditFieldTri_Dinter            matlab.ui.control.EditField
        EditFieldTri_Dfast             matlab.ui.control.EditField
        ResetButtonTri                 matlab.ui.control.Button
        LowerLimitEditField_f2         matlab.ui.control.NumericEditField
        LowerLimitEditFieldLabel_9     matlab.ui.control.Label
        UpperLimitEditField_f2         matlab.ui.control.NumericEditField
        UpperLimitEditFieldLabel_9     matlab.ui.control.Label
        LowerLimitEditField_f1         matlab.ui.control.NumericEditField
        LowerLimitEditFieldLabel_8     matlab.ui.control.Label
        UpperLimitEditField_f1         matlab.ui.control.NumericEditField
        UpperLimitEditFieldLabel_8     matlab.ui.control.Label
        StartPointEditField_f1         matlab.ui.control.NumericEditField
        StartPointEditFieldLabel_8     matlab.ui.control.Label
        LowerLimitEditField_D3         matlab.ui.control.NumericEditField
        LowerLimitEditFieldLabel_7     matlab.ui.control.Label
        UpperLimitEditField_D3         matlab.ui.control.NumericEditField
        UpperLimitEditFieldLabel_7     matlab.ui.control.Label
        StartPointEditField_D3         matlab.ui.control.NumericEditField
        StartPointEditFieldLabel_7     matlab.ui.control.Label
        LowerLimitEditField_D2         matlab.ui.control.NumericEditField
        LowerLimitEditFieldLabel_6     matlab.ui.control.Label
        UpperLimitEditField_D2         matlab.ui.control.NumericEditField
        UpperLimitEditFieldLabel_6     matlab.ui.control.Label
        StartPointEditField_D2         matlab.ui.control.NumericEditField
        StartPointEditFieldLabel_6     matlab.ui.control.Label
        LowerLimitEditField_D1         matlab.ui.control.NumericEditField
        LowerLimitEditFieldLabel_5     matlab.ui.control.Label
        StartPointEditField_f2         matlab.ui.control.NumericEditField
        StartPointEditFieldLabel_9     matlab.ui.control.Label
        StartPointEditField_D1         matlab.ui.control.NumericEditField
        StartPointEditFieldLabel_5     matlab.ui.control.Label
        UpperLimitEditField_D1         matlab.ui.control.NumericEditField
        UpperLimitEditFieldLabel_5     matlab.ui.control.Label
        IVIMSettings                   matlab.ui.container.Panel
        EditFieldIVIM_f                matlab.ui.control.EditField
        EditFieldIVIM_Dp               matlab.ui.control.EditField
        EditFieldIVIM_Dt               matlab.ui.control.EditField
        ResetButtonIVIM                matlab.ui.control.Button
        StartPointEditField_f          matlab.ui.control.NumericEditField
        StartPointEditFieldLabel_4     matlab.ui.control.Label
        LowerLimitEditField_Dp         matlab.ui.control.NumericEditField
        LowerLimitEditFieldLabel_3     matlab.ui.control.Label
        UpperLimitEditField_Dp         matlab.ui.control.NumericEditField
        UpperLimitEditFieldLabel_3     matlab.ui.control.Label
        StartPointEditField_Dp         matlab.ui.control.NumericEditField
        StartPointEditFieldLabel_3     matlab.ui.control.Label
        LowerLimitEditField_Dt         matlab.ui.control.NumericEditField
        LowerLimitEditFieldLabel_2     matlab.ui.control.Label
        UpperLimitEditField_f          matlab.ui.control.NumericEditField
        UpperLimitEditFieldLabel_4     matlab.ui.control.Label
        LowerLimitEditField_f          matlab.ui.control.NumericEditField
        LowerLimitEditFieldLabel_4     matlab.ui.control.Label
        BThresholdEditField            matlab.ui.control.NumericEditField
        BThresholdEditFieldLabel       matlab.ui.control.Label
        UpperLimitEditField_Dt         matlab.ui.control.NumericEditField
        UpperLimitEditFieldLabel_2     matlab.ui.control.Label
        StartPointEditField_Dt         matlab.ui.control.NumericEditField
        StartPointEditFieldLabel_2     matlab.ui.control.Label
        ADCSettings                    matlab.ui.container.Panel
        ResetButtonADC                 matlab.ui.control.Button
        UpperLimitEditFieldADC         matlab.ui.control.NumericEditField
        UpperLimitEditFieldLabel       matlab.ui.control.Label
        LowerLimitEditFieldADC         matlab.ui.control.NumericEditField
        LowerLimitEditFieldLabel       matlab.ui.control.Label
        StartPointEditFieldADC         matlab.ui.control.NumericEditField
        StartPointEditFieldLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        width;
        height;
        vol ;
        instanceSize=0;
        showBval=0;
        bvals;
        sliceNum=1;
        roi;
        pos;
        posx1;
        posx2;
        posy1;
        posy2;
        M;
        N;
        Z;
        sigData = [0];
        s_s0;
        adcfit;
        ADC=0;
        segFitHigh;
        segFitAll;
        freeFit;
        sigDataHigh;
        bvalHigh;
        paramFree;
        paramSeg;
        Dt=0;
        Dp=0;
        pF=0;
        collection;
        rows;
        cols;
        rsqr;
        mapMask;
        meanImage;
        ADCmap;
        Dtmap;
        Dpmap;
        pFmap;
        fo;
        fotype;
        segfo;
        fo2;
        foadc;
        fo_triexp;
        triexptype;
        mymodel;
        DpSegmap;
        DtSegmap;
        pFSegmap;
        dfastmap;
        dintermap;
        dslowmap;
        ffastmap;
        fintermap;
        fslowmap;
        kernel;
        DpUpperLimitfree = 0.05;
        DtUpperLimitfree = 0.005;
        DpUpperLimitseg = 0.05;
        DtUpperLimitseg = 0.005;
        DpUpperLimitBayes = 0.05;
        DtUpperLimitBayes = 0.005;
        bvals_ivim;
        s_s0_ivim;
        b_map_ivim;
        patientvalue;
        sequencevalue;
        patienttable;
        viewVol;
        viewSlidervalue;
        viewM;
        viewN;
        viewZ;
        cmaps = ["gray","jet","turbo","hsv","parula","hot","cool","sky","winter"];
        combmask;
        adctype;
        frames;
        seriestime;
        smoothDegree = 0.5;
        triexp_result;
        triexp_f1;
        triexp_f2;
        triexp_f3;
        triexp_d1;
        triexp_d2;
        triexp_d3;


        contrastLowLimit=0;
        contrastUpLimit=2.5;
        contrastValue;
        brightnessValue=0;
        viewImage;
        viewAdjustedImage;
        
        selectedTab;
        boldVol;
        TEvals;
        showTEval = 0
        boldInstace = 0;
        boldSlice = 1;
        R2star;
        boldmask;
        boldfittype;
        boldopts;
        bM;
        bN;
        bZ;
        OEFvol;
        dPbayesMap;
        dTbayesMap;
        pFbayesMap;
        DpMCMCmap;
        DtMCMCmap;
        PfMCMCmap;
        movingValue;
        fixedValue;
        fixedVol;
        moveVol;
        fixedImage;
        moveImage;
        fixedM;
        fixedN;
        fixedZ;
        moveM;
        moveN;
        moveZ;
        fixedSliderValue;
        movingSliderValue;
        regFix;
        regMove;
        registerStruct;
        moveLoc;
        fixLoc;
        calcLoc;

      
        fixThick;
        moveThick;

        SegmenterImage;
        segmentRegion;
        segmentForegroundPoints;
        segmentBackgroundPoints;
        segmentMasks;
        medsam;
        sam;
        samembedds;
        medembedds;
        imageSegmented; % Description
        segmentMask;

        Prevol;
        preM;
        preN;
        preZ;
        segmentFixedRegion;
        segmentMovingRegion;
        regSegFix;
        regSegMove;
        SegFixEmbd;
        SegMoveEmbd;
        segmentFixedMask;
        segmentMoveMask;
        segmentedFixed;
        segmentedMove;

        Cortex;
        Medulla;
        CortexMask;
        MedullaMask;

        CortexGtRoi;
        CortexGt;
        MedullaGt;
        drawSegmented;


        JaccardCortex;
        DiceCortex;
        JaccardMedulla;
        DiceMedulla;
        
        MultiROIStorage = [];
        vol_backup = [];% Description
    end
    
    methods (Access = public)
        
        function [posterior_mean, posterior_std, chain,Rsquare,fitSignal] = bayesian(app,b_values, signal_data,params_init,params_lower,params_upper)

    % Define the IVIM model

    model_fun = @(params, b) params(1) * exp(-b * params(2)) + (1-params(1)) * exp(-b * params(3));


    prior_f = @(f) unifpdf(f, params_lower(1), params_upper(1)); 

    prior_Dstar = @(Dstar) unifpdf(Dstar, params_lower(2), params_upper(2)); 

    prior_D = @(D) unifpdf(D, params_lower(3), params_upper(3)); 


    likelihood_fun = @(params, b, signal) prod(normpdf(signal, model_fun(params, b), 0.01));


    bayes_model = @(params) prior_f(params(1)) * prior_Dstar(params(2)) * prior_D(params(3)) * likelihood_fun(params, b_values, signal_data);


    n_samples = 1000;


    chain = zeros(n_samples, 3);


    for i = 1:n_samples

        params_prop = params_init + randn(1, 3) .* [0.1 0.01 0.001]; 

        params_prop(1) = max(0, min(1, params_prop(1))); 

        params_prop(2) = max(0, params_prop(2)); 

        params_prop(3) = max(0, params_prop(3)); 

        posterior_prop = bayes_model(params_prop);

        posterior_init = bayes_model(params_init);

        alpha = min(1, posterior_prop / posterior_init);

        if rand < alpha

            params_init = params_prop;

        end

        chain(i, :) = params_init;

    end


    posterior_mean = mean(chain, 1);

    posterior_std = std(chain, 0, 1);


    % fprintf('Posterior mean (f, D*, D): [%f, %f, %f]\n', posterior_mean);
    % 
    % fprintf('Posterior standard deviation (f, D*, D): [%f, %f, %f]\n', posterior_std);


    fitSignal = model_fun(posterior_mean, b_values);
    residual = signal_data - fitSignal;
    S_res_ss = sum(residual.^2);

    S_tot_ss = sum((signal_data - mean(signal_data)).^2);

    Rsquare = 1 - (S_res_ss / S_tot_ss);

    % plot(b_values, signal_data, 'o', b_values, fitSignal, '-','Parent',axes);
    % figure;
    % 
    % subplot(1, 3, 1);
    % 
    % histogram(chain(:, 1), 20);
    % 
    % xlabel('Perfusion Fraction (f)');
    % 
    % ylabel('Frequency');
    % 
    % title('Posterior Distribution of f');
    % 
    % 
    % subplot(1, 3, 2);
    % 
    % histogram(chain(:, 2), 20);
    % 
    % xlabel('Pseudo-Diffusion Coefficient (D*)');
    % 
    % ylabel('Frequency');
    % 
    % title('Posterior Distribution of D*');
    % 
    % 
    % subplot(1, 3, 3);
    % 
    % histogram(chain(:, 3), 20);
    % 
    % xlabel('True Diffusion Coefficient (D)');
    % 
    % ylabel('Frequency');
    % 
    % title('Posterior Distribution of D');
    % 
        end


        function [MOVINGREG] = registerImages(app,MOVING,FIXED)
            fixedRefObj = imref2d(size(FIXED));
movingRefObj = imref2d(size(MOVING));

% Intensity-based registration
[optimizer, metric] = imregconfig('multimodal');
metric.NumberOfSpatialSamples = 500;
metric.NumberOfHistogramBins = 50;
metric.UseAllPixels = true;
optimizer.GrowthFactor = 1.050000;
optimizer.Epsilon = 1.50000e-06;
optimizer.InitialRadius = 5.25000e-03;
optimizer.MaximumIterations = 100;

% Align centers
fixedCenterXWorld = mean(fixedRefObj.XWorldLimits);
fixedCenterYWorld = mean(fixedRefObj.YWorldLimits);
movingCenterXWorld = mean(movingRefObj.XWorldLimits);
movingCenterYWorld = mean(movingRefObj.YWorldLimits);
translationX = fixedCenterXWorld - movingCenterXWorld;
translationY = fixedCenterYWorld - movingCenterYWorld;

% Coarse alignment
initTform = affinetform2d();
initTform.A(1:2,3) = [translationX ; translationY];

% Apply Gaussian blur
fixedInit = imgaussfilt(FIXED,1.000000);
movingInit = imgaussfilt(MOVING,1.000000);

% Normalize images
movingInit = mat2gray(movingInit);
fixedInit = mat2gray(fixedInit);

% Apply transformation
tform = imregtform(movingInit,movingRefObj,fixedInit,fixedRefObj,'similarity',optimizer,metric,'PyramidLevels',3,'InitialTransformation',initTform);
MOVINGREG.Transformation = tform;
MOVINGREG.RegisteredImage = imwarp(MOVING, movingRefObj, tform, 'OutputView', fixedRefObj, 'SmoothEdges', true);

% Nonrigid registration
[MOVINGREG.DisplacementField,MOVINGREG.RegisteredImage] = imregdemons(MOVINGREG.RegisteredImage,FIXED,100,'AccumulatedFieldSmoothing',1.0,'PyramidLevels',3);

% Store spatial referencing object
MOVINGREG.SpatialRefObj = fixedRefObj;
            
        end
        function [cortex, cortexBW, medulla, medullaBW] = segmentCortexMedulla(app, RegisteredImage)
    % 1. Görüntüyü Standartlaştır
    if size(RegisteredImage, 3) > 1
        imgRaw = im2gray(RegisteredImage);
    else
        imgRaw = RegisteredImage;
    end
    
    % Adaptive Threshold için 0-1 aralığı
    imgDouble = im2double(imadjust(mat2gray(imgRaw)));

    % 2. KORTEKS (Senin Kodun)
    BW = imbinarize(imgDouble, 'adaptive', 'Sensitivity', 0.500000, 'ForegroundPolarity', 'bright');
    
    cortexBW = BW;
    % Korteksi biraz tıraşlayalım (Ayrımı netleştirmek için)
    cortexBW = imerode(cortexBW, strel('disk', 1));
    
    % Korteks Çıktısı
    cortex = imgDouble;
    cortex(~cortexBW) = 0;
    
    % --- DÜZELTME BURADA BAŞLIYOR (Kırmızı Halkayı Yok Etme) ---
    
    % Adım A: "Bütün Böbrek" maskesini oluştur.
    % Korteks dış kabuktur. Delikleri doldurursak böbreğin tamamını elde ederiz.
    wholeKidneyMask = imfill(cortexBW, 'holes');
    
    % Eğer korteks halkası tam kapalı değilse imfill çalışmayabilir.
    % Garanti olsun diye önce biraz kapatıp (imclose) sonra dolduralım:
    wholeKidneyMask = imclose(wholeKidneyMask, strel('disk', 5)); 
    wholeKidneyMask = imfill(wholeKidneyMask, 'holes');

    % Adım B: Güvenli Bölge (Safe Zone) oluştur.
    % Bütün böbreği kenarlardan 3 piksel içeri çekiyoruz (Erode).
    % Böylece en dıştaki o hatalı kırmızı halka dışarıda kalacak.
    safeZone = imerode(wholeKidneyMask, strel('disk', 3));
    
    % -----------------------------------------------------------
    
    % 3. MEDULLA HESABI
    medullaRaw = imsubtract(imgDouble, cortex);
    medullaBW = imbinarize(medullaRaw);
    
    % --- KRİTİK HAMLE ---
    % Medulla maskesini 'safeZone' ile sınırla.
    % Bu işlem, o dışarıdaki kırmızı halkayı %100 siler.
    medullaBW = medullaBW & safeZone;
    
    % Temizlik (Küçük gürültüleri at)
   
    % Medulla Çıktısı
    medulla = imgDouble;
    medulla(~medullaBW) = 0;
end
    end
    
    methods (Access = private)
        
  function bVal = getUniversalBValue(app, info)
   
    fprintf('\n==================================================\n');
    [~, fname, ext] = fileparts(info.Filename);
    fprintf('DOSYA: %s%s\n', fname, ext);

    bVal = [];

    
    if isfield(info, 'SequenceName')
        seqName = info.SequenceName;
        tokens = regexp(seqName, 'b(\d+)', 'tokens');
        if ~isempty(tokens)
            val = str2double(tokens{1}{1});
            fprintf('  [SequenceName] Found: "%s" -> Extracted: %d\n', seqName, val);
            
        else
            fprintf('  [SequenceName] Exist ("%s") but"b" value Nan.\n', seqName);
        end
    else
        fprintf('  [SequenceName] None.\n');
    end

    
    if isfield(info, 'DiffusionBValue')
        bVal = info.DiffusionBValue;
        fprintf('  [STANDART TAG] (0018,9087) DiffusionBValue: %d\n', bVal);
    else
        fprintf('  [STANDART TAG] (0018,9087) Not Found.\n');
    end

    
    if isempty(bVal)
        val = searchTagByHex(app, info, '0019', '100c');
        if ~isempty(val)
            bVal = double(val);
            fprintf('  [SIEMENS] (0019,100C) Found -> Value: %d\n', bVal);
        end
    end

   
    if isempty(bVal)
        val = searchTagByHex(app, info, '0043', '1039');
        if ~isempty(val)
            val = double(val(1)); 
            if val > 5000, val = val / 1000; end 
            bVal = val;
            fprintf('  [GE] (0043,1039) Found -> Raw: %d, Corrrected: %d\n', double(val(1)), bVal);
        end
    end

    
    if isempty(bVal)
        val = searchTagByHex(app, info, '2001', '1003');
        if isempty(val)
            val = searchTagByHex(app, info, '2005', '10b0');
        end
        
        if ~isempty(val)
            bVal = double(val);
            fprintf('  [PHILIPS] Found -> Value: %d\n', bVal);
        end
    end

    
    if isempty(bVal)
        if isfield(info, 'SequenceName')
            tokens = regexp(info.SequenceName, 'b(\d+)', 'tokens');
            if ~isempty(tokens)
                bVal = str2double(tokens{1}{1});
                fprintf('  [FALLBACK] Tag not found, SequenceName using: %d\n', bVal);
            end
        end
    end

   
    if isempty(bVal) || isnan(bVal)
        bVal = 0;
        fprintf('  [SONUÇ] Failed. 0 declared.\n');
    else
       
        oldB = bVal;
        if bVal > 20
            bVal = round(bVal / 10) * 10;
        end
        fprintf('  [SONUÇ] Final B-value: %d (raw: %d)\n', bVal, oldB);
    end
end


function val = searchTagByHex(app, info, group, element)
    val = [];
    
    fields = fieldnames(info);
    
    
    targetSig1 = [lower(group), lower(element)];      
    targetSig2 = [lower(group), '_', lower(element)]; 
    
    for i = 1:length(fields)
        thisField = lower(fields{i});
        
        
        if contains(thisField, targetSig1) || contains(thisField, targetSig2)
            val = info.(fields{i});
           
            return; 
        end
    end
end

function calculateAndPlotROI(app)
  
    if isempty(app.roi) || ~isvalid(app.roi)
        return;
    end

   
    checkedNodes = app.BValueTree.CheckedNodes;
    
    selectedB = [];
    for i = 1:length(checkedNodes)
        data = checkedNodes(i).NodeData;
        if isnumeric(data) 
            selectedB = [selectedB, data];
        end
    end

   
    if isempty(selectedB)
        cla(app.UIAxes2);
        return;
    end

    
    mask = createMask(app.roi);
    
    
    activeIndices = [];
    filteredBVals = [];
    
    for k = 1:length(selectedB)
        val = selectedB(k);
        [minDiff, bestIdx] = min(abs(app.bvals - val));
        if minDiff < 0.1
            activeIndices = [activeIndices, bestIdx];
            filteredBVals = [filteredBVals, app.bvals(bestIdx)];
        end
    end
    
  
    activeIndices = unique(sort(activeIndices));
    filteredBVals = unique(sort(filteredBVals));
    
    
    filteredSigData = [];
    
    for k = 1:length(activeIndices)
        origIdx = activeIndices(k);
        volIdx = origIdx + ((app.sliceNum - 1) * length(app.bvals));
        
        if volIdx <= size(app.vol, 3)
            tempvol = app.vol(:,:,volIdx);
            meanData = mean(tempvol(mask));
            filteredSigData = [filteredSigData, meanData];
        end
    end

    
    if ~isempty(filteredSigData)
        
        if filteredSigData(1) ~= 0
            s_s0_plot = filteredSigData ./ filteredSigData(1);
        else
            s_s0_plot = zeros(size(filteredSigData));
        end

        
        plot(app.UIAxes2, filteredBVals, s_s0_plot, '-o', 'LineWidth', 2, 'Color', 'b', 'MarkerFaceColor', 'b');
        
        title(app.UIAxes2, 'Single ROI Analysis (Filtered)');
        xlabel(app.UIAxes2, 'B-Value');
        ylabel(app.UIAxes2, 'S / S0');
        grid(app.UIAxes2, 'on');
        
      
        if length(app.bvals) > 1
             xlim(app.UIAxes2, [min(app.bvals)-10, max(app.bvals)+50]);
             ylim(app.UIAxes2, [-0.1, 1.1]);
        end
    end
end
        
        
        function recursiveMultiDraw(app)
   
    if app.MultiRoiSwitch.Value == "Off" || ~isvalid(app.UIAxes)
        calculateMultiROI(app); 
        return;
    end

    title(app.UIAxes, 'Çizim Yapın (ESC ile bitirin, ROI''leri hareket ettirebilirsiniz)');
    
    try
        newROI = [];
        
        switch app.ROIStyleDropDown.Value
            case "Rectangle", newROI = drawrectangle('Parent', app.UIAxes);
            case "Polygon",   newROI = drawpolygon('Parent', app.UIAxes);
            case "Freehand",  newROI = drawfreehand('Parent', app.UIAxes);
            case "Assisted",  newROI = drawassisted(findobj(app.UIAxes,'Type','Image'));
        end
        
        
        if isempty(newROI) || ~isvalid(newROI) || isempty(newROI.Position)
            app.MultiRoiSwitch.Value = "Off";
            calculateMultiROI(app);
            return;
        end


        app.MultiROIStorage = [app.MultiROIStorage, newROI];
        
        
        addlistener(newROI, 'ROIMoved', @(src, evt) calculateMultiROI(app));
        
        
        addlistener(newROI, 'DeletingROI', @(src, evt) calculateMultiROI(app));
        
        
        calculateMultiROI(app);
        
        
        drawnow limitrate;
        recursiveMultiDraw(app);
        
    catch
        app.MultiRoiSwitch.Value = "Off";
    end
end
        
        
function finishMultiROI(app)
    title(app.UIAxes, 'Multi-ROI Calculation Done');
    
    
    app.combmask = imbinarize(app.combmask);
    
    
    app.sigData = [0]; 
    
   
    if ~isequal(size(app.combmask), [app.M, app.N])
        app.combmask = imresize(app.combmask, [app.M, app.N]);
    end

    
    for i = 0:1:length(app.bvals)-1
        
        idx = (i+1)+((app.sliceNum-1)*length(app.bvals));
        
        
        if idx <= size(app.vol, 3)
            tempvol = app.vol(:,:,idx);
            meanData = mean(tempvol(app.combmask));
            app.sigData = horzcat(app.sigData, meanData);
        end
    end
    
    
    if length(app.sigData) > 1
        app.sigData = app.sigData(2:end);
        
        if app.sigData(1) ~= 0
            app.s_s0 = app.sigData ./ app.sigData(1);
        else
            app.s_s0 = zeros(size(app.sigData));
        end

        plot(app.bvals, app.s_s0, 'Parent', app.UIAxes2, 'LineWidth', 2, 'Marker', 's');
        title(app.UIAxes2, 'Multi-ROI Signal Data');
        xlabel(app.UIAxes2, 'B-Value');
        ylabel(app.UIAxes2, 'S / S0');
        grid(app.UIAxes2, 'on');
    end
    
    
end
        
   function calculateMultiROI(app)
    
    checkedNodes = app.BValueTree.CheckedNodes;
    
    selectedB = [];
    for i = 1:length(checkedNodes)
        data = checkedNodes(i).NodeData;
        if isnumeric(data)
            selectedB = [selectedB, data];
        end
    end

    if isempty(selectedB)
        cla(app.UIAxes2);
        return;
    end

    
    combinedMask = false(app.M, app.N);
    validROIs = [];
    
    
    if isempty(app.MultiROIStorage) && ~isempty(app.roi) && isvalid(app.roi)
        
        app.MultiROIStorage = [app.roi];
    end

    for i = 1:length(app.MultiROIStorage)
        thisROI = app.MultiROIStorage(i);
        if isvalid(thisROI)
            mask = createMask(thisROI);
            combinedMask = combinedMask | mask;
            validROIs = [validROIs, thisROI];
        end
    end
    app.MultiROIStorage = validROIs;

    if ~any(combinedMask(:))
        return;
    end

    
    activeIndices = [];
    filteredBVals = [];
    
    for k = 1:length(selectedB)
        val = selectedB(k);
        [minDiff, bestIdx] = min(abs(app.bvals - val));
        if minDiff < 0.1
            activeIndices = [activeIndices, bestIdx];
            filteredBVals = [filteredBVals, app.bvals(bestIdx)];
        end
    end
    
    activeIndices = unique(sort(activeIndices));
    filteredBVals = unique(sort(filteredBVals));
    
    filteredSigData = [];

    for k = 1:length(activeIndices)
        origIdx = activeIndices(k); 
        volIdx = origIdx + ((app.sliceNum - 1) * length(app.bvals));
        
        if volIdx <= size(app.vol, 3)
            tempvol = app.vol(:,:,volIdx);
            meanData = mean(tempvol(combinedMask));
            filteredSigData = [filteredSigData, meanData];
        end
    end

    
    if ~isempty(filteredSigData)
        if filteredSigData(1) ~= 0
            s_s0_plot = filteredSigData ./ filteredSigData(1);
        else
            s_s0_plot = zeros(size(filteredSigData));
        end

        plot(app.UIAxes2, filteredBVals, s_s0_plot, '-s', 'LineWidth', 2, 'Color', 'r', 'MarkerFaceColor', 'r');
        
        title(app.UIAxes2, 'Multi/Live ROI Analysis (Filtered)');
        xlabel(app.UIAxes2, 'B-Value');
        ylabel(app.UIAxes2, 'S / S0');
        grid(app.UIAxes2, 'on');
        
        if length(app.bvals) > 1
            xlim(app.UIAxes2, [min(app.bvals)-10, max(app.bvals)+50]);
            ylim(app.UIAxes2, [-0.1, 1.1]);
        end
    end
end
        
        
function zLoc = getSliceLocation(~, info)
    if isfield(info, 'SliceLocation')
        zLoc = info.SliceLocation;
    elseif isfield(info, 'ImagePositionPatient')
        zLoc = info.ImagePositionPatient(3);
    else
        zLoc = 0;
    end
end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('Startup Fonksiyonu Çalıştı!');
            screenSize = get(0, 'ScreenSize'); 
    screenWidth = screenSize(3);
    screenHeight = screenSize(4);

  
    appWidth = 1600;
    appHeight = 900;

  
    if screenWidth < appWidth
        appWidth = screenWidth - 50; 
    end
    
    if screenHeight < appHeight
        appHeight = screenHeight - 80; 
    end

    
    xPos = (screenWidth - appWidth) / 2;
    yPos = (screenHeight - appHeight) / 2;

    
    if yPos < 1
        yPos = 50; 
    end
    
    app.DiffusonCalculatorUIFigure.Position = [xPos, yPos, appWidth, appHeight];
    
app.StartPointEditFieldADC.Value = 0.001;
app.LowerLimitEditFieldADC.Value = 0;
app.UpperLimitEditFieldADC.Value = 0.005;


app.StartPointEditField_f.Value = 0.2;
app.LowerLimitEditField_f.Value = 0;
app.UpperLimitEditField_f.Value = 1;


app.StartPointEditField_Dp.Value = 0.05;
app.LowerLimitEditField_Dp.Value = 0.005;
app.UpperLimitEditField_Dp.Value = 0.5;


app.StartPointEditField_Dt.Value = 0.0015;
app.LowerLimitEditField_Dt.Value = 0;
app.UpperLimitEditField_Dt.Value = 0.005;


app.BThresholdEditField.Value = 200;


app.StartPointEditField_f1.Value = 0.1; 
app.StartPointEditField_f2.Value = 0.1;

app.StartPointEditField_D1.Value = 0.01;  
app.StartPointEditField_D2.Value = 0.003; 
app.StartPointEditField_D3.Value = 0.0005; 

app.LowerLimitEditField_f1.Value = 0;
app.LowerLimitEditField_f2.Value = 0;
app.LowerLimitEditField_D1.Value = 0.005;
app.LowerLimitEditField_D2.Value = 0.0005;
app.LowerLimitEditField_D3.Value = 0.0001;

app.UpperLimitEditField_f1.Value = 0.8;
app.UpperLimitEditField_f2.Value = 0.8;
app.UpperLimitEditField_D1.Value =  0.1;
app.UpperLimitEditField_D2.Value = 0.02;
app.UpperLimitEditField_D3.Value = 0.005;

color_f  = [1.0, 0.85, 0.85]; 
color_Dp = [0.85, 0.95, 1.0]; 
color_Dt = [0.85, 1.0, 0.85]; 
color_f2 = [0.7,0.3,0.7];

app.StartPointEditField_f.BackgroundColor = color_f;
app.LowerLimitEditField_f.BackgroundColor = color_f;
app.UpperLimitEditField_f.BackgroundColor = color_f;

app.StartPointEditField_Dp.BackgroundColor = color_Dp;
app.LowerLimitEditField_Dp.BackgroundColor = color_Dp;
app.UpperLimitEditField_Dp.BackgroundColor = color_Dp;

app.StartPointEditField_Dt.BackgroundColor = color_Dt;
app.LowerLimitEditField_Dt.BackgroundColor = color_Dt;
app.UpperLimitEditField_Dt.BackgroundColor = color_Dt;


app.StartPointEditField_f1.BackgroundColor = color_f;
app.UpperLimitEditField_f1.BackgroundColor = color_f;
app.LowerLimitEditField_f1.BackgroundColor = color_f;

app.StartPointEditField_f2.BackgroundColor = color_f2;
app.UpperLimitEditField_f2.BackgroundColor = color_f2;
app.LowerLimitEditField_f2.BackgroundColor = color_f2;

app.StartPointEditField_D1.BackgroundColor = color_Dp;
app.UpperLimitEditField_D1.BackgroundColor = color_Dp;
app.LowerLimitEditField_D1.BackgroundColor = color_Dp;

app.StartPointEditField_D3.BackgroundColor = color_Dt;
app.UpperLimitEditField_D3.BackgroundColor = color_Dt;
app.LowerLimitEditField_D3.BackgroundColor = color_Dt;

app.StartPointEditField_D2.BackgroundColor = [1.0, 1.0, 0.85];
app.UpperLimitEditField_D2.BackgroundColor = [1.0, 1.0, 0.85];
app.LowerLimitEditField_D2.BackgroundColor = [1.0, 1.0, 0.85];

app.EditFieldIVIM_Dt.BackgroundColor = color_Dt;
app.EditFieldIVIM_Dt.Value = "Dt";
app.EditFieldIVIM_Dp.BackgroundColor = color_Dp;
app.EditFieldIVIM_Dp.Value = "Dp";
app.EditFieldIVIM_f.BackgroundColor = color_f;
app.EditFieldIVIM_f.Value = "f";

app.EditFieldTri_Dfast.BackgroundColor = color_Dp;
app.EditFieldTri_Dfast.Value = "D Fast";
app.EditFieldTri_Dinter.BackgroundColor = [1.0, 1.0, 0.85];
app.EditFieldTri_Dinter.Value = "D Inter";
app.EditFieldTri_Dslow.BackgroundColor = color_Dt;
app.EditFieldTri_Dslow.Value = "D Slow";
app.EditFieldTri_f1.BackgroundColor = color_f;
app.EditFieldTri_f1.Value = "f Fast";
app.EditFieldTri_f2.BackgroundColor = color_f2;
app.EditFieldTri_f2.Value = "f Inter";

app.MovingSliceSlider.Enable = 'off';

    
        end

        % Button pushed function: LoadDICOMButton
        function LoadDICOMButtonPushed(app, event)
            
            folder = uigetdir;
            try
            
            app.collection = dicomCollection(folder,IncludeSubfolders=true);
            path = pwd;
            foldername = 'database';
            if ~exist(fullfile(path,foldername),'dir')
                mkdir(path,foldername)
            else
                savepath = [path '\database'];
                savefile = app.collection
                save(fullfile(savepath,'Data.mat'),'savefile');
            end
            app.PatientsListBox.Items = unique(app.collection.PatientName);
            % app.PatientsListBox.ItemsData = 1:length(app.PatientsListBox.Items);
            app.patientvalue = app.PatientsListBox.Value;
            app.SequencesListBox.Items = app.collection.SeriesDescription(find(app.collection.PatientName == app.patientvalue));
            app.SequencesListBox.ItemsData = 1:length(app.SequencesListBox.Items);
            app.FixedListBox.Items = app.SequencesListBox.Items;
            app.FixedListBox.ItemsData = app.SequencesListBox.ItemsData;
            app.MovingListBox.Items = app.SequencesListBox.Items;
            app.MovingListBox.ItemsData = app.SequencesListBox.ItemsData;
            app.sequencevalue = app.SequencesListBox.Items(app.SequencesListBox.Value);
            app.ColormapDropDown.Items = app.cmaps;
            app.ColorMapsDropDown.Items = app.cmaps;
            app.SmoothingDegreeEditField.Value = app.smoothDegree;
            app.StateField.Value = "DICOM Collection Installed. See Dicom Tab.";
            app.StateField.BackgroundColor = [1.0,0.48,0.51];
            savepath = [path '\database'];
            savefile = app.collection
            save(fullfile(savepath,'Data.mat'),'savefile');
            catch
                app.StateField.Value = "Error Occured";
            end


        end

        % Value changed function: DrawROIButton
        function DrawROIButtonValueChanged(app, event)
      
      
          app.sigData = [0];
    
   
    app.MultiROIStorage = []; 
    app.MultiRoiSwitch.Value = "Off";
    
    delete(findall(app.UIAxes, 'Type', 'images.roi.Rectangle'));
    delete(findall(app.UIAxes, 'Type', 'images.roi.Polygon'));
    delete(findall(app.UIAxes, 'Type', 'images.roi.Freehand'));
    delete(findall(app.UIAxes, 'Type', 'images.roi.AssistedFreehand'));
    
   
    if app.ROIStyleDropDown.Value == "Rectangle"
        app.roi = drawrectangle('Parent', app.UIAxes);
    elseif app.ROIStyleDropDown.Value == "Polygon"
        app.roi = drawpolygon('Parent', app.UIAxes);
    elseif app.ROIStyleDropDown.Value == "Freehand"
        app.roi = drawfreehand('Parent', app.UIAxes);
    elseif app.ROIStyleDropDown.Value == "Assisted"
        app.roi = drawassisted(findobj(app.UIAxes, 'Type', 'Image'));
    end

   
    if ~isempty(app.roi) && isvalid(app.roi)
        calculateAndPlotROI(app);
        addlistener(app.roi, 'ROIMoved', @(src, evt) calculateAndPlotROI(app));
    end
        
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
          

   try
        app.StateField.Value = "Starting Calculation...";
        app.StateField.BackgroundColor = [0.94, 0.94, 0.94];
        pause(0.01);


        checkedNodes = app.BValueTree.CheckedNodes;
        
        selectedB = [];
        for i = 1:length(checkedNodes)
            data = checkedNodes(i).NodeData;
            if isnumeric(data)
                selectedB = [selectedB, data];
            end
        end
        
        if length(selectedB) < 3
            app.StateField.Value = "Error: Select at least 3 b-values!";
            app.StateField.BackgroundColor = [1, 0.5, 0.5];
            return;
        end


        combinedMask = false(app.M, app.N);
        maskFound = false;

       
        validROIs = [];
        if ~isempty(app.MultiROIStorage)
            for i = 1:length(app.MultiROIStorage)
                if isvalid(app.MultiROIStorage(i))
                    validROIs = [validROIs, app.MultiROIStorage(i)];
                end
            end
        end
        app.MultiROIStorage = validROIs; 

        
        if ~isempty(app.MultiROIStorage)
            for i = 1:length(app.MultiROIStorage)
                combinedMask = combinedMask | createMask(app.MultiROIStorage(i));
            end
            maskFound = true;
            
        end

      
        if ~maskFound
            if ~isempty(app.roi) && isvalid(app.roi)
                combinedMask = createMask(app.roi);
                maskFound = true;
            else
                
                allROIs = findall(app.UIAxes);
                for k = 1:length(allROIs)
                    obj = allROIs(k);
                    if contains(class(obj), 'images.roi') && isvalid(obj)
                        combinedMask = createMask(obj);
                        maskFound = true;
                        app.roi = obj; 
                        break; 
                    end
                end
            end
        end
        
        if ~any(combinedMask(:))
            app.StateField.Value = "Error: No ROI found.";
            app.StateField.BackgroundColor = [1, 0.5, 0.5];
            return;
        end


        b_use = [];
        s_use = [];
        
        
        activeIndices = [];
        for k = 1:length(selectedB)
            val = selectedB(k);
            [minDiff, bestIdx] = min(abs(app.bvals - val));
            if minDiff < 0.1
                activeIndices = [activeIndices, bestIdx];
            end
        end
        activeIndices = unique(sort(activeIndices));
        
       
        rawSignal = [];
        for k = 1:length(activeIndices)
            origIdx = activeIndices(k);
            
            
            volIdx = origIdx + ((app.sliceNum - 1) * length(app.bvals));
            
            if volIdx <= size(app.vol, 3)
                tempvol = app.vol(:,:,volIdx);
                meanData = mean(tempvol(combinedMask));
                
                rawSignal = [rawSignal, meanData];
                b_use = [b_use, app.bvals(origIdx)];
            end
        end
        
        
        if isempty(rawSignal)
             app.StateField.Value = "Error: Signal extraction failed.";
             return;
        end
        
        if rawSignal(1) ~= 0
            s_use = rawSignal ./ rawSignal(1);
        else
            s_use = zeros(size(rawSignal));
        end

        
        if iscolumn(b_use), b_use = b_use'; end
        if iscolumn(s_use), s_use = s_use'; end

       
        app.fotype = fittype('f * exp(-b * D_star) + (1 - f) * exp(-b * D)', ...
            'independent', 'b', 'coefficients', {'f', 'D_star', 'D'});
        app.fo = fitoptions('Method','NonLinearLeastSquares','Algorithm','Levenberg-Marquardt',...
            'StartPoint',[app.StartPointEditField_f.Value, app.StartPointEditField_Dp.Value, app.StartPointEditField_Dt.Value], 'Lower',[app.LowerLimitEditField_f.Value,app.LowerLimitEditField_Dp.Value,app.LowerLimitEditField_Dt.Value], 'Upper',[app.UpperLimitEditField_f.Value,app.UpperLimitEditField_Dp.Value,app.UpperLimitEditField_Dt.Value]); 
            
        app.segfo = fitoptions('Method','NonLinearLeastSquares','Algorithm','Levenberg-Marquardt');
        app.fo2 = fitoptions('Method','NonLinearLeastSquares','Algorithm','Levenberg-Marquardt',...
            'StartPoint',[0.01],'Lower',[0],'Upper',[0.5]);
            
        app.adctype = fittype('exp(-b*ADC)', 'independent', 'b', 'coefficients', {'ADC'});
        app.foadc = fitoptions('Method','NonLinearLeastSquares','Algorithm','Levenberg-Marquardt','StartPoint',[app.StartPointEditFieldADC.Value],'Lower',[app.LowerLimitEditFieldADC.Value],'Upper',[app.UpperLimitEditFieldADC.Value]);
        
        app.triexptype = fittype('S0 * (f1 * exp(-b * D1) + f2 * exp(-b * D2) + (1 - f1 - f2) * exp(-b * D3))', ...
            'independent', 'b', 'coefficients', {'f1', 'f2', 'D1', 'D2', 'D3', 'S0'});
        app.fo_triexp = fitoptions('Method','NonLinearLeastSquares','Algorithm','Levenberg-Marquardt', ...
            'StartPoint',[app.StartPointEditField_f1.Value, app.StartPointEditField_f2.Value, app.StartPointEditField_D1.Value, app.StartPointEditField_D2.Value, app.StartPointEditField_D3.Value, 1], 'Lower',[app.LowerLimitEditField_f1.Value, app.LowerLimitEditField_f2.Value, app.LowerLimitEditField_D1.Value, app.LowerLimitEditField_D2.Value, app.LowerLimitEditField_D3.Value, 0.5], 'Upper',[app.UpperLimitEditField_f1.Value, app.UpperLimitEditField_f2.Value, app.UpperLimitEditField_D1.Value, app.UpperLimitEditField_D2.Value, app.UpperLimitEditField_D3.Value, 2]);

        app.rsqr = 0;

        
        if app.MonoExpButton.Value
            [app.adcfit, gadc] = fit(b_use.', s_use.', app.adctype, app.foadc);
            app.rsqr = gadc.rsquare;
            app.ADCEditField.Value = app.adcfit.ADC;
            
           
            app.DpEditField.Value=0; app.DtEditField.Value=0; app.pFEditField.Value=0;
            app.DfastEditField.Value=0; app.DinterEditField.Value=0; app.DslowEditField.Value=0;
            app.pFfastEditField.Value=0; app.pFinterEditField.Value=0; app.pFslowEditField.Value=0;
            
            plot(app.UIAxes2, app.adcfit, b_use, s_use);
        end
        
        
        if app.BiexpFreeButton.Value
            [app.freeFit, gfree] = fit(b_use.', s_use.', app.fotype, app.fo);
            app.paramFree = [app.freeFit.D, app.freeFit.D_star, app.freeFit.f];
            app.rsqr = gfree.rsquare;
            
            app.DtEditField.Value = app.paramFree(1);
            app.DpEditField.Value = app.paramFree(2);
            app.pFEditField.Value = app.paramFree(3);
            app.ADCEditField.Value = 0;
            
            
            app.DfastEditField.Value=0; app.DinterEditField.Value=0; app.DslowEditField.Value=0;
            app.pFfastEditField.Value=0; app.pFinterEditField.Value=0; app.pFslowEditField.Value=0;
            
            plot(app.UIAxes2, app.freeFit, b_use, s_use);
        end
        
        
        if app.BiExpSegButton.Value
            highB_indices = b_use > app.BThresholdEditField.Value;
            if sum(highB_indices) >= 2
                bHigh = b_use(highB_indices);
                sHigh = s_use(highB_indices);
                
                app.segFitHigh = fit(bHigh.', sHigh.', 'exp1', app.segfo);
                dt_seg = -app.segFitHigh.b;
                intercept = app.segFitHigh.a;
                pf_seg = (1 - intercept); if pf_seg < 0, pf_seg = 0; end
                
                pixelmodel = @(DP, x) (pf_seg * exp(-x * DP) + (1 - pf_seg) * exp(-x * dt_seg));
                [app.segFitAll, gseg] = fit(b_use.', s_use.', pixelmodel, app.fo2);
                
                app.paramSeg = [app.segFitAll.DP, dt_seg, pf_seg];
                app.rsqr = gseg.rsquare;
                
                app.DpEditField.Value = app.paramSeg(1);
                app.DtEditField.Value = app.paramSeg(2);
                app.pFEditField.Value = app.paramSeg(3);
                app.ADCEditField.Value = 0;
                
                
                app.DfastEditField.Value=0; app.DinterEditField.Value=0; app.DslowEditField.Value=0;
                app.pFfastEditField.Value=0; app.pFinterEditField.Value=0; app.pFslowEditField.Value=0;

                fittedCurve = (pf_seg * exp(-b_use * app.paramSeg(1))) + ((1 - pf_seg) * exp(-b_use * dt_seg));
                plot(app.UIAxes2, b_use, s_use, 'o', b_use, fittedCurve, '-r');
            else
                app.StateField.Value = "Error: Not enough high b-values.";
            end
        end
        
        
        if app.BayesianButton.Value
           
            [p, ~, ~, rsqrb, fits] = bayesian(app, b_use, s_use,[app.StartPointEditField_f.Value, app.StartPointEditField_Dp.Value, app.StartPointEditField_Dt.Value],[app.LowerLimitEditField_f.Value,app.LowerLimitEditField_Dp.Value,app.LowerLimitEditField_Dt.Value],[app.UpperLimitEditField_f.Value,app.UpperLimitEditField_Dp.Value,app.UpperLimitEditField_Dt.Value]);
            
            app.rsqr = rsqrb;
            app.pFEditField.Value = p(1);
            app.DpEditField.Value = p(2);
            app.DtEditField.Value = p(3);
            app.ADCEditField.Value = 0;
            
           
            app.DfastEditField.Value=0; app.DinterEditField.Value=0; app.DslowEditField.Value=0;
            app.pFfastEditField.Value=0; app.pFinterEditField.Value=0; app.pFslowEditField.Value=0;
            
            plot(app.UIAxes2, b_use, s_use, 'o', b_use, fits, '-');
        end
        
        
        if app.TriExpButton.Value
            if length(b_use) < 6
                app.StateField.Value = "Warning: Tri-Exp needs more points!";
            end
            
            [app.triexp_result, gtriexp] = fit(b_use.', s_use.', app.triexptype, app.fo_triexp);
            app.rsqr = gtriexp.rsquare;
            
            app.DfastEditField.Value = app.triexp_result.D1;
            app.DinterEditField.Value = app.triexp_result.D2;
            app.DslowEditField.Value = app.triexp_result.D3;
            app.pFfastEditField.Value = app.triexp_result.f1;
            app.pFinterEditField.Value = app.triexp_result.f2;
            app.pFslowEditField.Value = 1 - app.triexp_result.f1 - app.triexp_result.f2;
            
            app.ADCEditField.Value = 0; app.DpEditField.Value = 0; app.DtEditField.Value = 0; app.pFEditField.Value = 0;
            
            plot(app.UIAxes2, app.triexp_result, b_use, s_use);
        end
        
       
        app.RSquareEditField.Value = app.rsqr;
        title(app.UIAxes2, 'Fitting Graphic (Filtered)');
        xlabel(app.UIAxes2, 'B-Value');
        ylabel(app.UIAxes2, 'S / S0');
        ylim(app.UIAxes2, [-0.1, 1.1]);
        grid(app.UIAxes2, 'on');
        
        app.StateField.Value = "Calculation Done.";
        app.StateField.BackgroundColor = [0.29, 0.84, 0.16];

    catch ME
        app.StateField.Value = "Fit Error: " + ME.message;
        app.StateField.BackgroundColor = [1, 0.5, 0.5];
        disp(ME.message);
    end
        end

        % Value changed function: BValueKnob
        function BValueKnobValueChanged(app, event)
           
           
            currentBVal = app.BValueKnob.Value;
            app.showBval = find(abs(app.bvals - currentBVal) < 0.01, 1);
            
            app.EditField.Value = currentBVal;
            
           
            numBVals = length(app.bvals);
            idx = (app.showBval) + ((app.sliceNum - 1) * numBVals);
            
            
            rawImage = app.vol(:,:,idx);
            
            
            if max(rawImage(:)) > 0
                safeImage = mat2gray(rawImage);
            else
                safeImage = rawImage;
            end
            
            
            imagesc(app.UIAxes, imadjust(safeImage));
            colormap(app.UIAxes, "gray");
            xlim(app.UIAxes, [0, app.N]);
            ylim(app.UIAxes, [0, app.M]);
            title(app.UIAxes, 'IMAGE');
 
            
        end

        % Value changed function: SliceKnob
        function SliceKnobValueChanged(app, event)
           
            app.sliceNum = app.SliceKnob.Value;
            
            
            currentBVal = app.BValueKnob.Value;
            app.showBval = find(abs(app.bvals - currentBVal) < 0.01, 1);
            
           
            app.LocationEditField.Value = app.calcLoc(app.sliceNum);
            app.EditField_2.Value = app.SliceKnob.Value;
            
            
            numBVals = length(app.bvals);
            idx = (app.showBval) + ((app.sliceNum - 1) * numBVals);
            
            
            rawImage = app.vol(:,:,idx);
            
            
            if max(rawImage(:)) > 0
                safeImage = mat2gray(rawImage);
            else
                safeImage = rawImage;
            end
            
            imagesc(app.UIAxes, imadjust(safeImage));
            colormap(app.UIAxes, "gray");
            xlim(app.UIAxes, [0, app.N]);
            ylim(app.UIAxes, [0, app.M]);
            title(app.UIAxes, 'IMAGE');
      
            
        end

        % Button pushed function: GenerateMapsButton
        function GenerateMapsButtonPushed(app, event)
               app.StateField.Value = "Map Generation Started...";
    app.StateField.BackgroundColor = [0.95, 0.84, 0.18]; 
    pause(0.1);

    try
       
        
        checkedNodes = app.BValueTree.CheckedNodes;
        selectedB = [];
        for i = 1:length(checkedNodes)
            data = checkedNodes(i).NodeData;
            if isnumeric(data)
                selectedB = [selectedB, data];
            end
        end
        
        
        if length(selectedB) < 3
            app.StateField.Value = "Error: Select at least 3 b-values for Maps!";
            app.StateField.BackgroundColor = [1, 0.5, 0.5];
            return;
        end
        
       
        activeIndices = [];
        for k = 1:length(selectedB)
            val = selectedB(k);
            [minDiff, bestIdx] = min(abs(app.bvals - val));
            if minDiff < 0.1
                activeIndices = [activeIndices, bestIdx];
            end
        end
        activeIndices = unique(sort(activeIndices));
        
        
        b_map_use = app.bvals(activeIndices);
        
        
        if iscolumn(b_map_use), b_map_use = b_map_use'; end


        useMulti = (app.mapRoiSwitch.Value == "MultiROI");
        
        app.mapMask = false(app.M, app.N);
        maskFound = false;

        if useMulti
            
            if ~isempty(app.MultiROIStorage)
                for i = 1:length(app.MultiROIStorage)
                    if isvalid(app.MultiROIStorage(i))
                        app.mapMask = app.mapMask | createMask(app.MultiROIStorage(i));
                        maskFound = true;
                    end
                end
            end
            
            if ~maskFound && any(app.combmask(:))
                app.mapMask = logical(app.combmask);
                maskFound = true;
            end
        end
        
        if ~maskFound 
            if ~isempty(app.roi) && isvalid(app.roi)
                app.mapMask = createMask(app.roi);
            else
                
                app.StateField.Value = "Error: Define an ROI for Map Generation.";
                app.StateField.BackgroundColor = [1, 0.5, 0.5];
                return;
            end
        end
        
        [maskX, maskY] = size(app.mapMask);
        app.kernel = app.KernelSizeEditField.Value;
%Fit parametersten çek
       
        app.fotype = fittype('f * exp(-b * D_star) + (1 - f) * exp(-b * D)','independent', 'b', 'coefficients', {'f', 'D_star', 'D'});
        app.fo = fitoptions('Method','NonLinearLeastSquares','Algorithm','Levenberg-Marquardt',...
            'StartPoint',[app.StartPointEditField_f.Value, app.StartPointEditField_Dp.Value, app.StartPointEditField_Dt.Value], 'Lower',[app.LowerLimitEditField_f.Value,app.LowerLimitEditField_Dp.Value,app.LowerLimitEditField_Dt.Value], 'Upper',[app.UpperLimitEditField_f.Value,app.UpperLimitEditField_Dp.Value,app.UpperLimitEditField_Dt.Value]); 
            
      
        app.segfo = fitoptions('Method','NonLinearLeastSquares','Algorithm','Levenberg-Marquardt');
        app.fo2 = fitoptions('Method','NonLinearLeastSquares','Algorithm','Levenberg-Marquardt',...
            'StartPoint',[0.01],'Lower',[0],'Upper',[0.5]);
            
        
        app.adctype = fittype('exp(-b*x)', 'independent', 'x', 'dependent', 'y');
        app.foadc = fitoptions('Method','NonLinearLeastSquares','Algorithm','Levenberg-Marquardt','StartPoint',[app.StartPointEditFieldADC.Value],'Lower',[app.LowerLimitEditFieldADC.Value],'Upper',[app.UpperLimitEditFieldADC.Value]);
        
        app.triexptype = fittype('S0 * (f1 * exp(-b * D1) + f2 * exp(-b * D2) + (1 - f1 - f2) * exp(-b * D3))', ...
            'independent', 'b', 'coefficients', {'f1', 'f2', 'D1', 'D2', 'D3', 'S0'});
        app.fo_triexp = fitoptions('Method','NonLinearLeastSquares','Algorithm','Levenberg-Marquardt', ...
            'StartPoint',[app.StartPointEditField_f1.Value, app.StartPointEditField_f2.Value, app.StartPointEditField_D1.Value, app.StartPointEditField_D2.Value, app.StartPointEditField_D3.Value, 1], 'Lower',[app.LowerLimitEditField_f1.Value, app.LowerLimitEditField_f2.Value, app.LowerLimitEditField_D1.Value, app.LowerLimitEditField_D2.Value, app.LowerLimitEditField_D3.Value, 0.5], 'Upper',[app.UpperLimitEditField_f1.Value, app.UpperLimitEditField_f2.Value, app.UpperLimitEditField_D1.Value, app.UpperLimitEditField_D2.Value, app.UpperLimitEditField_D3.Value, 2]);
        
        app.ADCmap = zeros(maskX, maskY);
        app.Dpmap = zeros(maskX, maskY); app.Dtmap = zeros(maskX, maskY); app.pFmap = zeros(maskX, maskY);
        app.DpSegmap = zeros(maskX, maskY); app.DtSegmap = zeros(maskX, maskY); app.pFSegmap = zeros(maskX, maskY);
        app.dPbayesMap = zeros(maskX, maskY); app.dTbayesMap = zeros(maskX, maskY); app.pFbayesMap = zeros(maskX, maskY);
        app.dfastmap = zeros(maskX, maskY);app.dintermap = zeros(maskX, maskY);app.dslowmap = zeros(maskX, maskY);app.ffastmap = zeros(maskX, maskY);app.fintermap = zeros(maskX, maskY);app.fslowmap = zeros(maskX, maskY); 
        for xc = round(app.kernel/2)+1 : 1 : maskX-app.kernel
            for yc = round(app.kernel/2)+1 : 1 : maskY-app.kernel
                
                
                if app.mapMask(xc, yc) ~= 0
                    
                    
                    pixelSigMatrix = [];
                    
                    for k = 1:length(activeIndices)
                        origIdx = activeIndices(k);
                        volIdx = origIdx + ((app.sliceNum - 1) * length(app.bvals));
                        
                        
                        kernelRegion = app.vol(xc-(round(app.kernel/2)):xc+(round(app.kernel/2)), ...
                                               yc-(round(app.kernel/2)):yc+(round(app.kernel/2)), ...
                                               volIdx);
                        pixelSigMatrix = [pixelSigMatrix, mean2(kernelRegion)];
                    end
                    
                    
                    if pixelSigMatrix(1) > 0
                        pixelSigMatrix = pixelSigMatrix ./ pixelSigMatrix(1);
                    else
                        pixelSigMatrix = zeros(size(pixelSigMatrix));
                    end
                    
                    
                    if iscolumn(pixelSigMatrix), pixelSigMatrix = pixelSigMatrix'; end


                    try
                        pixelADCfit = fit(b_map_use.', pixelSigMatrix.', app.adctype, app.foadc);
                        app.ADCmap(xc,yc) = pixelADCfit.b; 
                    catch
                        app.ADCmap(xc,yc) = 0;
                    end
                    
                 
                    try
                        pixelFreefit = fit(b_map_use.', pixelSigMatrix.', app.fotype, app.fo);
                        app.Dpmap(xc,yc) = pixelFreefit.D_star;
                        app.Dtmap(xc,yc) = pixelFreefit.D;
                        app.pFmap(xc,yc) = pixelFreefit.f;
                    catch
                        app.Dpmap(xc,yc) = 0; app.Dtmap(xc,yc) = 0; app.pFmap(xc,yc) = 0;
                    end
                    
                    
                    try
                        
                        highB_indices = b_map_use > 200;
                        if sum(highB_indices) >= 2
                            bHigh = b_map_use(highB_indices);
                            sHigh = pixelSigMatrix(highB_indices);
                            
                            pixelsegFitHigh = fit(bHigh.', sHigh.', 'exp1', app.segfo);
                            DT = -pixelsegFitHigh.b;
                            intercept = pixelsegFitHigh.a;
                            PF = (1 - intercept); if PF < 0, PF = 0; end
                            
                            pixelmodel = @(DP,x) (PF*(exp(-x*DP)) + (1-PF)*exp(-x*DT));
                            pixelSegAllFit = fit(b_map_use.', pixelSigMatrix.', pixelmodel, app.fo2);
                            
                            app.DpSegmap(xc,yc) = pixelSegAllFit.DP;
                            app.DtSegmap(xc,yc) = DT;
                            app.pFSegmap(xc,yc) = PF;
                        else
                           
                             app.DpSegmap(xc,yc) = 0; app.DtSegmap(xc,yc) = 0; app.pFSegmap(xc,yc) = 0;
                        end
                    catch
                        app.DpSegmap(xc,yc) = 0; app.DtSegmap(xc,yc) = 0; app.pFSegmap(xc,yc) = 0;
                    end
                    
                   
                    bayes_init  = [app.StartPointEditField_f.Value, app.StartPointEditField_Dp.Value, app.StartPointEditField_Dt.Value];
                    bayes_lower = [app.LowerLimitEditField_f.Value, app.LowerLimitEditField_Dp.Value, app.LowerLimitEditField_Dt.Value];
                    bayes_upper = [app.UpperLimitEditField_f.Value, app.UpperLimitEditField_Dp.Value, app.UpperLimitEditField_Dt.Value];
                    try
                        [pmap,~,~,~,~] = bayesian(app, b_map_use, pixelSigMatrix,bayes_init,bayes_lower,bayes_upper);
                        app.dPbayesMap(xc,yc) = pmap(2);
                        app.dTbayesMap(xc,yc) = pmap(3);
                        app.pFbayesMap(xc,yc) = pmap(1);
                    catch
                        app.dPbayesMap(xc,yc) = 0; app.dTbayesMap(xc,yc) = 0; app.pFbayesMap(xc,yc) = 0;
                    end
                    try
                        pixelTriexpfit = fit(b_map_use.', pixelSigMatrix.', app.triexptype, app.fo_triexp);
                        app.dfastmap(xc,yc) = pixelTriexpfit.D1;app.dintermap(xc,yc)=pixelTriexpfit.D2;app.dslowmap(xc,yc) = pixelTriexpfit.D3;app.ffastmap(xc,yc) = pixelTriexpfit.f1;app.fintermap(xc,yc)=pixelTriexpfit.f2;app.fslowmap(xc,yc)=1-(pixelTriexpfit.f1+pixelTriexpfit.f2);
                    catch
                        app.dfastmap(xc,yc) = 0;app.dintermap(xc,yc)=0;app.dslowmap(xc,yc) = 0;app.ffastmap(xc,yc) = 0;app.fintermap(xc,yc)=0;app.fslowmap(xc,yc)=0; 
                    end
                end 
            end
        end 

       
        imagesc(app.UIAxes3_dt, app.ADCmap);
        colormap(app.UIAxes3_dt,"jet"); colorbar(app.UIAxes3_dt);
        xlim(app.UIAxes3_dt,[0, app.N]); ylim(app.UIAxes3_dt,[0, app.M]);
        app.UIAxes_dp.Visible = 'off';
        app.UIAxes3_f.Visible ='off';
        
        imagesc(app.UIAxesD1, app.dfastmap);
        colormap(app.UIAxesD1,"jet"); colorbar(app.UIAxesD1);
        xlim(app.UIAxesD1,[0, app.N]); ylim(app.UIAxesD1,[0, app.M]);

        imagesc(app.UIAxesD2, app.dintermap);
        colormap(app.UIAxesD2,"jet"); colorbar(app.UIAxesD2);
        xlim(app.UIAxesD2,[0, app.N]); ylim(app.UIAxesD2,[0, app.M]);

        imagesc(app.UIAxesD3, app.dslowmap);
        colormap(app.UIAxesD3,"jet"); colorbar(app.UIAxesD3);
        xlim(app.UIAxesD3,[0, app.N]); ylim(app.UIAxesD3,[0, app.M]);

        imagesc(app.UIAxesf1, app.ffastmap);
        colormap(app.UIAxesf1,"jet"); colorbar(app.UIAxesf1);
        xlim(app.UIAxesf1,[0, app.N]); ylim(app.UIAxesf1,[0, app.M]);

        imagesc(app.UIAxesf2, app.fintermap);
        colormap(app.UIAxesf2,"jet"); colorbar(app.UIAxesf2);
        xlim(app.UIAxesf2,[0, app.N]); ylim(app.UIAxesf2,[0, app.M]);

        imagesc(app.UIAxesf3, app.fslowmap);
        colormap(app.UIAxesf3,"jet"); colorbar(app.UIAxesf3);
        xlim(app.UIAxesf3,[0, app.N]); ylim(app.UIAxesf3,[0, app.M]);

        app.StateField.Value = "Map Calculation Done (Filtered).";
        app.StateField.BackgroundColor = [0.29, 0.84, 0.16]; % Yeşil

    catch ME
        app.StateField.Value = "Map Error: " + ME.message;
        app.StateField.BackgroundColor = [1, 0.5, 0.5];
        disp(ME.message);
    end

            
        end

        % Button pushed function: LoadtoCalculatorButton
        function LoadtoCalculatorButtonPushed(app, event)
           
            app.DicomState.Value = "Metadata Reading & Sorting...";
            pause(0.1);
            app.vol_backup = [];
            app.UndoFilterButton.Enable = 'off';
            try
                
                app.patienttable = app.collection(app.collection.PatientName == app.patientvalue, :);
                descIdx = find(strcmp(app.patienttable.SeriesDescription, app.sequencevalue));
                realIdx = descIdx(app.SequencesListBox.Value == app.SequencesListBox.ItemsData(descIdx)); 
                
                if isempty(realIdx), realIdx = descIdx(1); end
                
                fileNamesCell = app.patienttable.Filenames(realIdx);
                fileNames = fileNamesCell{1}; 
                
                
                numFiles = length(fileNames);
                
                fileInfoStruct = struct('Path', cell(numFiles,1), 'SliceLoc', zeros(numFiles,1), 'BVal', zeros(numFiles,1));
                
                for i = 1:numFiles
                    f = fileNames{i};
                    info = dicominfo(f);
                    
                   
                    if isfield(info, 'SliceLocation')
                        fileInfoStruct(i).SliceLoc = info.SliceLocation;
                    elseif isfield(info, 'ImagePositionPatient')
                        fileInfoStruct(i).SliceLoc = info.ImagePositionPatient(3);
                    else
                        fileInfoStruct(i).SliceLoc = i; 
                    end
                    
                  
                    fileInfoStruct(i).BVal = getUniversalBValue(app, info);
                    fileInfoStruct(i).Path = f;
                end
                
               
                T = struct2table(fileInfoStruct);
                
                
                T = sortrows(T, {'SliceLoc', 'BVal'});
                
                uniqueBvals = unique(T.BVal);
                uniqueSlices = unique(T.SliceLoc);
                
                numB = length(uniqueBvals);
                numSlices = length(uniqueSlices);


                tempImg = dicomread(T.Path{1});
                [rows, cols] = size(tempImg);
                
                sortedVol = zeros(rows, cols, numSlices * numB, 'double'); 
                
                counter = 1;
                app.DicomState.Value = "Reconstructing Volume...";
                
                
                tol = 0.01; 
                
                for s = 1:numSlices
                    for b = 1:numB
                        
                        
                        sliceMatch = abs(T.SliceLoc - uniqueSlices(s)) < tol;
                        bvalMatch = abs(T.BVal - uniqueBvals(b)) < tol;
                        
                        targetRow = T(sliceMatch & bvalMatch, :);
                        
                        if ~isempty(targetRow)
                            
                            rawData = dicomread(targetRow.Path{1});
                            sortedVol(:,:,counter) = double(rawData);
                        else
                            
                            sortedVol(:,:,counter) = zeros(rows, cols); 
                        end
                        counter = counter + 1;
                    end
                end
                
                
                app.vol = sortedVol; 
                app.bvals = uniqueBvals'; 
                app.calcLoc = uniqueSlices';
                
                [app.M, app.N, app.Z] = size(app.vol);
                app.instanceSize = numSlices;
                
                
                app.SliceKnob.Items = string(1:app.instanceSize);
                app.SliceKnob.ItemsData = 1:app.instanceSize;
                app.SliceKnob.Value = 1;
                app.sliceNum = 1;
                
                app.BValueKnob.Items = string(app.bvals);
                app.BValueKnob.ItemsData = app.bvals;
                app.BValueKnob.Value = app.bvals(1);
                app.showBval = 1;
               
                firstSlice = app.vol(:,:,1);
                
                
                if max(firstSlice(:)) > 0
                    safeImage = mat2gray(firstSlice); 
                else
                    safeImage = firstSlice;
                end
                
                
                imagesc(app.UIAxes, imadjust(safeImage));
                colormap(app.UIAxes, "gray");
                title(app.UIAxes, 'IMAGE');
                xlim(app.UIAxes, [0, app.N]);
                ylim(app.UIAxes, [0, app.M]);
                
                app.DicomState.Value = "Vendor-Neutral Load Complete";
                app.StateField.Value = "";
                cleanB = double(app.bvals); 


delete(app.BValueTree.Children);


cleanB = sort(double(app.bvals));


rootNode = uitreenode(app.BValueTree);
rootNode.Text = "Tüm B-Değerleri";
rootNode.NodeData = "ROOT"; 


for i = 1:length(cleanB)
    val = cleanB(i);
    childNode = uitreenode(rootNode);
    childNode.Text = sprintf('b = %g', val); 
    childNode.NodeData = val;                
end


expand(app.BValueTree);


app.BValueTree.CheckedNodes = [rootNode; rootNode.Children];
            catch ME
                app.DicomState.Value = "Error: " + ME.message;
            end
        
        end

        % Value changed function: SequencesListBox
        function SequencesListBoxValueChanged(app, event)
            app.sequencevalue = app.SequencesListBox.Items(app.SequencesListBox.Value);
            app.patienttable = app.collection(find(app.collection.PatientName == app.patientvalue),1:end);
            I = find(app.patienttable.SeriesDescription == app.sequencevalue);
            I = I(find(I == app.SequencesListBox.Value));
            app.frames = app.patienttable.Frames;
            app.seriestime = app.patienttable.SeriesDateTime;
            app.TimeEditField.Value = string(app.seriestime(I));
            app.FramesEditField.Value = app.frames(I);
            fileNames = app.patienttable.Filenames(I);
            app.ImageNumberSpinner.Value = 1;
            rowPre = app.patienttable.Rows(I);
            colPre = app.patienttable.Columns(I);
            app.Prevol = zeros(rowPre , colPre);
            fileNames = fileNames{1,1};
            for i = 1:1:length(fileNames)
                f = fileNames(i);


                svol = dicomread(f);
                app.Prevol = cat(3,app.Prevol,svol);

            
            end
            [app.preM,app.preN,app.preZ] = size(app.Prevol);
            app.Prevol = app.Prevol(:,:,2:end);
            imagesc(app.PreviewAxes,app.Prevol(:,:,1));
            colormap(app.PreviewAxes,'gray');
            xlim(app.PreviewAxes,[0,app.preN]);
            ylim(app.PreviewAxes,[0,app.preM]);
            app.ImageNumberSpinner.Limits=[1,app.preZ];

        end

        % Value changed function: PatientsListBox
        function PatientsListBoxValueChanged(app, event)
            app.patientvalue = app.PatientsListBox.Value;
            app.SequencesListBox.Items = app.collection.SeriesDescription(find(app.collection.PatientName == app.patientvalue));
            app.FixedListBox.Items = app.SequencesListBox.Items;
            app.MovingListBox.Items = app.SequencesListBox.Items;
            
        end

        % Button pushed function: LoadtoViewerButton
        function LoadtoViewerButtonPushed(app, event)
            app.DicomState.Value = "Loading..";
            pause(0.5);
             try
            app.patienttable = app.collection(find(app.collection.PatientName == app.patientvalue),1:end);
            I = find(app.patienttable.SeriesDescription == app.sequencevalue);
            I = I(find(I == app.SequencesListBox.Value));
            
            fileNames = app.patienttable.Filenames(I);
            fileNames = fileNames{1,1};
             % app.rows = app.patienttable.Rows(I);
            % app.cols = app.patienttable.Columns(I);
            app.viewVol = [];
            for i = 1:1:length(fileNames)
                f = fileNames(i);
                svol = dicomread(f);
                app.viewVol = cat(3,app.viewVol,svol);
            end
            [app.viewM, app.viewN, app.viewZ] = size(app.viewVol);
            
            app.viewVol = app.viewVol(:,:,1:end);
            
            imagesc(app.UIAxes8,imadjust(app.viewVol(:,:,app.ViewSlider.Limits(1))));
            colormap(app.UIAxes8,app.ColormapDropDown.Value);
            xlim(app.UIAxes8,[0 ,app.viewN]);
            ylim(app.UIAxes8,[0, app.viewM]);
            if app.viewZ > 1
            app.ViewSlider.Limits = [1,app.viewZ];
            app.ViewSlider.MajorTicks = 1:round(app.viewZ/10):app.viewZ;
            app.ViewSlider.MajorTickLabels = string(1:round(app.viewZ/10):app.viewZ);
            app.ViewSlider.MinorTicks = 1:1:app.viewZ;
            else
                app.ViewSlider.Limits = [0,1];
                app.ViewSlider.MajorTicks = 0:1;
                app.ViewSlider.MajorTickLabels = string(0:1);
                app.ViewSlider.MinorTicks = [];
            end
            app.ViewSlider.Value = 1;
            app.InstanceNumberEditField.Value = app.ViewSlider.Value;
            app.brightnessValue = 0;
            app.contrastLowLimit = 0;
            app.contrastUpLimit = 5;
            app.BritghtnessSlider.Value = 0;
            app.ContrastSlider.Value = [0 5];
            app.DicomState.Value = "Images Loaded";
              catch
                  app.DicomState.Value = "Error Occured";
              end
            
        end

        % Value changed function: ColorMapsDropDown
        function ColorMapsDropDownValueChanged(app, event)
            colormap(app.UIAxes_dp,app.ColorMapsDropDown.Value);
            colormap(app.UIAxes3_dt,app.ColorMapsDropDown.Value);
            colormap(app.UIAxes3_f,app.ColorMapsDropDown.Value);
            colormap(app.UIAxesD1,app.ColorMapsDropDown.Value);
            colormap(app.UIAxesD2,app.ColorMapsDropDown.Value);
            colormap(app.UIAxesD3,app.ColorMapsDropDown.Value);
            colormap(app.UIAxesf1,app.ColorMapsDropDown.Value);
            colormap(app.UIAxesf2,app.ColorMapsDropDown.Value);
            colormap(app.UIAxesf3,app.ColorMapsDropDown.Value);
            
            
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            colormap(app.UIAxes8,app.ColormapDropDown.Value);
            
        end

        % Button pushed function: FilterDWIBetaButton
        function FilterDWIBetaButtonPushed(app, event)
          app.StateField.Value = "Backing up & Filtering...";
    app.StateField.BackgroundColor = [0.95, 0.84, 0.18];
    drawnow;
    
    try
        
        if isempty(app.vol_backup)
            app.vol_backup = app.vol;
            
            app.UndoFilterButton.Enable = 'on';
        end
        
        
        app.smoothDegree = app.SmoothingDegreeEditField.Value;
        
        for idx = 1:1:app.Z
            patch = std2(app.vol(:,:,idx));
            
            app.vol(:,:,idx) = imnlmfilt(app.vol(:,:,idx), 'DegreeOfSmoothing', app.smoothDegree * patch);
        end
        
        
        currentIdx = (app.showBval) + ((app.sliceNum - 1) * length(app.bvals));
        if currentIdx <= size(app.vol, 3)
            currentImg = app.vol(:,:,currentIdx);
            if max(currentImg(:)) > 0
                safeImg = mat2gray(currentImg);
            else
                safeImg = currentImg;
            end
            imagesc(app.UIAxes, imadjust(safeImg));
            colormap(app.UIAxes, "gray");
            xlim(app.UIAxes, [0, app.N]);
            ylim(app.UIAxes, [0, app.M]);
        end

        app.StateField.Value = "Filter Applied. (Use Undo to Revert)";
        app.StateField.BackgroundColor = [0.29, 0.84, 0.16];

    catch ME
        app.StateField.Value = "Filter Error: " + ME.message;
        app.StateField.BackgroundColor = [1, 0.5, 0.5];
    end
        end

        % Button pushed function: DrawMultipleROIButton
        function DrawMultipleROIButtonPushed(app, event)
    
    app.MultiRoiSwitch.Value = "On";
    
    
    delete(findall(app.UIAxes, 'Type', 'images.roi.Rectangle'));
    delete(findall(app.UIAxes, 'Type', 'images.roi.Polygon'));
    delete(findall(app.UIAxes, 'Type', 'images.roi.Freehand'));
    delete(findall(app.UIAxes, 'Type', 'images.roi.AssistedFreehand'));
    
    app.MultiROIStorage = []; 
    
   
    recursiveMultiDraw(app);


        end

        % Value changing function: ContrastSlider
        function ContrastSliderValueChanging(app, event)
            app.contrastValue = event.Value;
            app.contrastLowLimit = app.contrastValue(1);
            app.contrastUpLimit = app.contrastValue(2);
            
            app.viewAdjustedImage = imadjust(app.viewVol(:,:,round(app.ViewSlider.Value)),[app.contrastLowLimit/255, app.contrastUpLimit/255],[])
            imagesc(app.UIAxes8,app.viewAdjustedImage+app.brightnessValue);
        end

        % Value changing function: ViewSlider
        function ViewSliderValueChanging(app, event)
            app.viewSlidervalue = round(event.Value);
            app.InstanceNumberEditField.Value = app.viewSlidervalue;
            app.viewImage = imadjust(app.viewVol(:,:,app.viewSlidervalue),[app.contrastLowLimit/255, app.contrastUpLimit/255],[]);
            imagesc(app.UIAxes8,app.viewImage + app.brightnessValue);
            colormap(app.UIAxes8,app.ColormapDropDown.Value);
            xlim(app.UIAxes8,[0 ,app.viewN]);
            ylim(app.UIAxes8,[0, app.viewM]);
        end

        % Value changing function: BritghtnessSlider
        function BritghtnessSliderValueChanging(app, event)
            app.brightnessValue = event.Value*(2^16);
            app.viewAdjustedImage = imadjust(app.viewVol(:,:,round(app.ViewSlider.Value)),[app.contrastLowLimit/255, app.contrastUpLimit/255],[])
            imagesc(app.UIAxes8,app.viewAdjustedImage+app.brightnessValue);
            
        end

        % Window scroll wheel function: DiffusonCalculatorUIFigure
        function DiffusonCalculatorUIFigureWindowScrollWheel(app, event)
            if app.selectedTab.Title == "Viewer"
            verticalScrollCount = event.VerticalScrollCount;
            scrollvalue = round(app.ViewSlider.Value) + verticalScrollCount;
            
            
            if scrollvalue < 1
                scrollvalue = 1
            elseif scrollvalue > app.ViewSlider.Limits(2)
                    scrollvalue = app.ViewSlider.Limits(2)
            end
            app.ViewSlider.Value = scrollvalue;
            app.InstanceNumberEditField.Value = scrollvalue;
            scrollImage = imadjust(app.viewVol(:,:,scrollvalue),[app.contrastLowLimit/255, app.contrastUpLimit/255],[]);
            imagesc(app.UIAxes8,scrollImage + app.brightnessValue);
            colormap(app.UIAxes8,app.ColormapDropDown.Value);
            xlim(app.UIAxes8,[0 ,app.viewN]);
            ylim(app.UIAxes8,[0, app.viewM]);
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.selectedTab = app.TabGroup.SelectedTab;
            
        end

        % Value changed function: mapRoiSwitch
        function mapRoiSwitchValueChanged(app, event)
            value = app.mapRoiSwitch.Value;
            
            if value == "MultiROI"
                app.GenerateMapsButton.BackgroundColor = [ 0.48,0.54,0.78];
            else
                app.GenerateMapsButton.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Button pushed function: LoadtoBOLDPcsButton
        function LoadtoBOLDPcsButtonPushed(app, event)
            app.patienttable = app.collection(find(app.collection.PatientName == app.patientvalue),1:end);
            I = find(app.patienttable.SeriesDescription == app.sequencevalue);
            % candidate = convertStringsToChars(descriptions(I));
            I = I(find(I == app.SequencesListBox.Value));
            % if candidate(1) == 'e'
                fileNames = app.patienttable.Filenames(I);
                app.frames = app.collection.Frames;
            % end
            
       
             app.rows = app.patienttable.Rows(I);
            app.cols = app.patienttable.Columns(I);
            app.boldVol = zeros(app.rows , app.cols);
            fileNames = fileNames{1,1};
            TEmatrix = [];
            for i = 1:1:length(fileNames)
                f = fileNames(i);
                info = dicominfo(f);
                TE = info.EchoTime;
                
                TEmatrix = horzcat(TEmatrix,TE);
                svol = dicomread(f);
                app.boldVol = cat(3,app.boldVol,svol);

            
            end
            app.TEvals = unique(TEmatrix);
            app.boldVol = app.boldVol(:,:,2:end);
            [app.bM, app.bN, app.bZ] = size(app.boldVol);
            disp(app.Z)
            disp(app.TEvals)
            app.boldInstace = app.bZ/length(app.TEvals);

            app.TEKnob.Items = string(app.TEvals);
            app.TEKnob.ItemsData = app.TEvals;
            app.BoldSliceKnob.Items = string(1:app.boldInstace);
            app.BoldSliceKnob.ItemsData = (1:app.boldInstace);
            imagesc(app.UIAxes10,imadjust(app.boldVol(:,:,1+(app.showTEval*app.boldInstace))));
            colormap(app.UIAxes10,"gray");
            title(app.UIAxes,'IMAGE');
            xlim(app.UIAxes10,[0,app.bM]);
            ylim(app.UIAxes10,[0,app.bN]);


        end

        % Value changed function: TEKnob
        function TEKnobValueChanged(app, event)
            app.showTEval = find(app.TEvals == app.TEKnob.Value);
            imagesc(app.UIAxes10,imadjust(app.boldVol(:,:,(app.showTEval)+((app.boldSlice-1)*length(app.TEvals)))));
            colormap(app.UIAxes10,"gray");
            xlim(app.UIAxes10,[0,app.bM]);
            ylim(app.UIAxes10,[0,app.bN]);
            
        end

        % Value changed function: BoldSliceKnob
        function BoldSliceKnobValueChanged(app, event)
            app.boldSlice = app.BoldSliceKnob.Value;
            app.showTEval = find(app.TEvals == app.TEKnob.Value);
            imagesc(app.UIAxes10,imadjust(app.boldVol(:,:,(app.showTEval)+((app.boldSlice-1)*length(app.TEvals)))));
            colormap(app.UIAxes10,"gray");
            xlim(app.UIAxes10,[0,app.bM]);
            ylim(app.UIAxes10,[0,app.bN]);
        end

        % Button pushed function: GenerateR2MapButton
        function GenerateR2MapButtonPushed(app, event)
            
            z = app.Z;
            app.R2star = zeros(app.M,app.N);
            [Xmask,Ymask] = size(app.boldmask);
            app.BoldState.Value = "Proccesing";
            pause(0.5)
            for k = 1:1:Xmask
                for j = 1:1:Ymask
                    boldmatrix = [0];
                    if app.boldmask(k,j) ~=0 
                    for m = 0:1:length(app.TEvals)-1
                        
                            boldpixel = app.boldVol(k,j,(m+1)+((app.boldSlice-1)*length(app.TEvals)));
                            boldmatrix = horzcat(boldmatrix,boldpixel);
                        
                    end
                    try
                    boldmatrix = boldmatrix(2:end);
                    boldmatrix = boldmatrix./boldmatrix(1);
                    boldfit = fit(app.TEvals.',boldmatrix.',app.boldfittype,app.boldopts);
                    
                    app.R2star(k,j) = boldfit.b*1000;
                    catch
                        app.R2star(k,j) = 0;
                    end
                    else
                        app.R2star(k,j) = 0;
                    end
                end
            end


            app.BoldState.Value = "Done";
            imagesc(app.UIAxes11,app.R2star,[0, max(app.R2star(:))]);
            colormap(app.UIAxes11,'turbo');
            colorbar(app.UIAxes11);
            xlim(app.UIAxes11,[0,app.bM]);
            ylim(app.UIAxes11,[0,app.bN]);
            app.BoldState.Value = "Done";
        end

        % Value changed function: DrawROIforFitButton
        function DrawROIforFitButtonValueChanged(app, event)
            delete(findall(app.UIAxes10,'Type','images.roi'));
            boldroi = drawassisted(findobj(app.UIAxes10,'Type','Image'));
            app.boldmask = createMask(boldroi);
            app.boldfittype = fittype( 'exp(-b*x)', 'independent', 'x', 'dependent', 'y' );
            app.boldopts =fitoptions( 'Method', 'NonlinearLeastSquares','Algorithm','Levenberg-Marquardt');
            app.boldopts.Display = 'Off';
            app.boldopts.StartPoint = 1;
            boldFitsig = [0];
            for i=1:1:length(app.TEvals)
                fittempvol = app.boldVol(:,:,i+((app.boldSlice-1)*length(app.TEvals)));
                fitBoldMean = mean(fittempvol(app.boldmask));
                boldFitsig =horzcat(boldFitsig,fitBoldMean);
            end
            boldFitsig = boldFitsig(2:end);
            boldFitsig = boldFitsig./boldFitsig(1);
            roifit = fit(app.TEvals.',boldFitsig.',app.boldfittype,app.boldopts);
            app.MeanR2msec1EditField.Value = roifit.b*1000;
           
            plot(app.UIAxes12,roifit,app.TEvals,boldFitsig);
        end

        % Button pushed function: SaveMapsButton
        function SaveMapsButtonPushed(app, event)
               folderPath = uigetdir(pwd, 'Select Folder to Save Maps');
    if folderPath == 0
        return; 
    end
    mapsToSave = struct();
                mapsToSave.adc = app.ADCmap;
               mapsToSave.dTfree = app.Dtmap;
               mapsToSave.dPfree = app.Dpmap;
               mapsToSave.pFfree = app.pFmap;
               mapsToSave.dtSegmented = app.DtSegmap;
               mapsToSave.dpSegmented = app.DpSegmap;
               mapsToSave.pfSegmented = app.pFSegmap;
               mapsToSave.dtBayesian = app.dTbayesMap;
               mapsToSave.dpBayesian = app.dPbayesMap;
              mapsToSave.pfBayesian = app.pFbayesMap;
              mapsToSave.Dfast = app.dfastmap;
              mapsToSave.Dinter = app.dintermap;
              mapsToSave.Dslow = app.dslowmap;
              mapsToSave.Ffast = app.ffastmap;
              mapsToSave.finter = app.fintermap;
              mapsToSave.fslow = app.fslowmap;
              mapNames = fieldnames(mapsToSave);
              excelFileName = fullfile(folderPath, 'IVIM_Results_Matrix.xlsx');
for i = 1:length(mapNames)
            mapName = mapNames{i};
            mapData = mapsToSave.(mapName);
            
           
            niftiFileName = fullfile(folderPath, [mapName '.nii']);
            niftiwrite(single(mapData), niftiFileName);
            
            
            writematrix(mapData, excelFileName, 'Sheet', mapName);
            
           
            matFileName = fullfile(folderPath, [mapName '.mat']);
             save(matFileName, 'mapData');
end
        end

        % Button pushed function: ImportFromLastDatabaseButton
        function ImportFromLastDatabaseButtonPushed(app, event)
            loadpath = pwd;
            loadpath = [loadpath,'\database','\Data.mat'];
            app.collection = load(loadpath);
            app.collection = app.collection.savefile;
            app.PatientsListBox.Items = unique(app.collection.PatientName);
            % app.PatientsListBox.ItemsData = 1:length(app.PatientsListBox.Items);
            app.patientvalue = app.PatientsListBox.Value;
            app.SequencesListBox.Items = app.collection.SeriesDescription(find(app.collection.PatientName == app.patientvalue));
            app.SequencesListBox.ItemsData = 1:length(app.SequencesListBox.Items);
            app.sequencevalue = app.SequencesListBox.Items(app.SequencesListBox.Value);
            app.FixedListBox.Items = app.SequencesListBox.Items;
            app.FixedListBox.ItemsData = app.SequencesListBox.ItemsData;
            app.MovingListBox.Items = app.SequencesListBox.Items;
            app.MovingListBox.ItemsData = app.SequencesListBox.ItemsData;
            app.ColormapDropDown.Items = app.cmaps;
            app.ColorMapsDropDown.Items = app.cmaps;
            app.SmoothingDegreeEditField.Value = app.smoothDegree;
            app.StateField.Value = "DICOM Collection Installed. See Dicom Tab.";
            app.StateField.BackgroundColor = [1.0,0.48,0.51];

        end

        % Button pushed function: SaveDICOMDatabaseButton
        function SaveDICOMDatabaseButtonPushed(app, event)
            data = app.collection;
            uisave({'data'},'DicomDataBase');
        end

        % Button pushed function: LoadDICOMDatabaseButton
        function LoadDICOMDatabaseButtonPushed(app, event)
            [fileSelected ,pathSelected] = uigetfile;
            app.collection = load(fullfile(pathSelected,fileSelected));
            app.collection = app.collection.data;
            app.PatientsListBox.Items = unique(app.collection.PatientName);
            % app.PatientsListBox.ItemsData = 1:length(app.PatientsListBox.Items);
            app.patientvalue = app.PatientsListBox.Value;
            app.SequencesListBox.Items = app.collection.SeriesDescription(find(app.collection.PatientName == app.patientvalue));
            app.SequencesListBox.ItemsData = 1:length(app.SequencesListBox.Items);
            app.FixedListBox.Items = app.SequencesListBox.Items;
            app.FixedListBox.ItemsData = app.SequencesListBox.ItemsData;
            app.MovingListBox.Items = app.SequencesListBox.Items;
            app.MovingListBox.ItemsData = app.SequencesListBox.ItemsData;
            app.sequencevalue = app.SequencesListBox.Items(app.SequencesListBox.Value);
            app.ColormapDropDown.Items = app.cmaps;
            app.ColorMapsDropDown.Items = app.cmaps;
            app.SmoothingDegreeEditField.Value = app.smoothDegree;
            app.StateField.Value = "DICOM Collection Installed. See Dicom Tab.";
            app.StateField.BackgroundColor = [1.0,0.48,0.51];
        end

        % Value changed function: FixedListBox
        function FixedListBoxValueChanged(app, event)
            app.fixedValue = app.FixedListBox.Items(app.FixedListBox.Value);
            app.patienttable = app.collection(find(app.collection.PatientName == app.patientvalue),1:end);
            Ifixed = find(app.patienttable.SeriesDescription == app.fixedValue);
            Ifixed = Ifixed(find(Ifixed == app.FixedListBox.Value));
            
        end

        % Value changed function: MovingListBox
        function MovingListBoxValueChanged(app, event)
            app.movingValue = app.MovingListBox.Items(app.MovingListBox.Value);
            app.patienttable = app.collection(find(app.collection.PatientName == app.patientvalue),1:end);
            Imoving = find(app.patienttable.SeriesDescription == app.movingValue);
            Imoving = Imoving(find(Imoving == app.MovingListBox.Value));
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
           

    app.RegisterStatusEditField.Value = "Analyzing IVIM...";
    pause(0.001);
    
   
    app.patienttable = app.collection(app.collection.PatientName == app.patientvalue, :);
    Ifixed = find(strcmp(app.patienttable.SeriesDescription, app.fixedValue));
    Ifixed = Ifixed(app.FixedListBox.Value == app.FixedListBox.ItemsData(Ifixed));
    
    fileNamesCell = app.patienttable.Filenames(Ifixed);
    fileNames = fileNamesCell{1}; 
    
    
    numFiles = length(fileNames);
    tempInfo = struct('Path', cell(numFiles,1), 'Slice', zeros(numFiles,1), 'BVal', zeros(numFiles,1));
    
    for i = 1:numFiles
        f = fileNames{i};
        info = dicominfo(f);
        
        tempInfo(i).Path = f;
        tempInfo(i).Slice = getSliceLocation(app, info);
        tempInfo(i).BVal = getUniversalBValue(app, info); 
        
        if i == 1, app.fixThick = info.SliceThickness; end
    end
    
    
    allB = [tempInfo.BVal];
    minB = min(allB); 
    mask = (allB == minB);
    filteredData = tempInfo(mask);
    
    
    T = struct2table(filteredData);
    T = sortrows(T, 'Slice');
    
   
    numSlices = height(T);
    tempImg = dicomread(T.Path{1});
    [M, N] = size(tempImg);
    
    app.fixedVol = zeros(M, N, numSlices);
    app.fixLoc = zeros(1, numSlices);
    
    for i = 1:numSlices
        app.fixedVol(:,:,i) = double(dicomread(T.Path{i}));
        app.fixLoc(i) = T.Slice(i); % Sıralı Konumlar
    end
    
    [app.fixedM, app.fixedN, app.fixedZ] = size(app.fixedVol);
    
    
    imagesc(app.UIAxes13, imadjust(mat2gray(app.fixedVol(:,:,1))));
    colormap(app.UIAxes13,'gray');
    xlim(app.UIAxes13,[0 ,app.fixedN]);
    ylim(app.UIAxes13,[0, app.fixedM]);
    
    
    if app.fixedZ > 1
        app.FixedSliceSlider.Limits = [1, app.fixedZ];
        app.FixedSliceSlider.MajorTicks = [1, app.fixedZ]; 
        app.FixedSliceSlider.MinorTicks = [];
    else
        app.FixedSliceSlider.Limits = [0, 1];
    end
    app.FixedSliceSlider.Value = 1;
    
    app.RegisterStatusEditField.Value = sprintf("Fixed Loaded (%d Slices, b=%d)", app.fixedZ, minB);

        end

        % Value changing function: FixedSliceSlider
        function FixedSliceSliderValueChanging(app, event)
        
    
    app.fixedSliderValue = round(event.Value);
    
    
    if app.fixedSliderValue < 1, app.fixedSliderValue = 1; end
    if app.fixedSliderValue > app.fixedZ, app.fixedSliderValue = app.fixedZ; end
    
    
    currentZ = app.fixLoc(app.fixedSliderValue);
    app.LocationEditField_2.Value = currentZ;
    app.CoveredDepthEditField.Value = currentZ + app.fixThick;
    
    
    app.fixedImage = imadjust(mat2gray(app.fixedVol(:,:,app.fixedSliderValue)));
    imagesc(app.UIAxes13, app.fixedImage);
    colormap(app.UIAxes13,'gray');
    xlim(app.UIAxes13,[0 ,app.fixedN]);
    ylim(app.UIAxes13,[0, app.fixedM]);
    
    
    if ~isempty(app.moveLoc) && ~isempty(app.moveVol)
        
        [minDiff, bestIdx] = min(abs(app.moveLoc - currentZ));
        
        
        if minDiff < 5.0
            
            app.MovingSliceSlider.Value = bestIdx;


            app.movingSliderValue = bestIdx;
            app.LocationEditField_3.Value = app.moveLoc(bestIdx);
            app.CoveredDepthEditField_2.Value = app.moveLoc(bestIdx) + app.moveThick;
            
            app.moveImage = imadjust(mat2gray(app.moveVol(:,:,bestIdx)));
            imagesc(app.UIAxes13_2, app.moveImage);
            xlim(app.UIAxes13_2,[0 ,app.moveN]);
            ylim(app.UIAxes13_2,[0, app.moveM]);
            
            app.RegisterStatusEditField.Value = sprintf("Matched (Diff: %.1f mm)", minDiff);
        else
            app.RegisterStatusEditField.Value = "No Match Found";
        end
    end

            
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
  

    app.RegisterStatusEditField.Value = "Analyzing Moving Image...";
    pause(0.001);


    app.patienttable = app.collection(find(app.collection.PatientName == app.patientvalue), 1:end);
    
    
    Imoving = find(app.patienttable.SeriesDescription == app.movingValue);
    
  
    Imoving = Imoving(find(Imoving == app.MovingListBox.Value));
    
   
    tempCell = app.patienttable.Filenames(Imoving);
    
    
    fileNames = tempCell{1};


    numFiles = length(fileNames);
    tempInfo = struct('Path', cell(numFiles,1), 'Slice', zeros(numFiles,1));
    
    for i = 1:numFiles
        f = fileNames(i);
        info = dicominfo(f);
        
        tempInfo(i).Path = f;
        
        tempInfo(i).Slice = getSliceLocation(app, info);
        
        if i == 1, app.moveThick = info.SliceThickness; end
    end
    
   
    T = struct2table(tempInfo);
    T = sortrows(T, 'Slice');
    
   
    [~, uniqueIdx] = unique(T.Slice, 'stable');
    T = T(uniqueIdx, :);
    
   
    numSlices = height(T);
    
   
    if iscell(T.Path)
        firstPath = T.Path{1};
    else
        firstPath = T.Path(1);
    end
    
    tempImg = dicomread(firstPath);
    [M, N] = size(tempImg);
    
    app.moveVol = zeros(M, N, numSlices);
    app.moveLoc = zeros(1, numSlices);
    
    for i = 1:numSlices
        if iscell(T.Path)
            p = T.Path{i};
        else
            p = T.Path(i);
        end
        
        app.moveVol(:,:,i) = double(dicomread(p));
        app.moveLoc(i) = T.Slice(i); 
    end
    
    [app.moveM, app.moveN, app.moveZ] = size(app.moveVol);
    
   
    imagesc(app.UIAxes13_2, imadjust(mat2gray(app.moveVol(:,:,1))));
    colormap(app.UIAxes13_2, 'gray');
    xlim(app.UIAxes13_2, [0, app.moveN]);
    ylim(app.UIAxes13_2, [0, app.moveM]);
    
    
    if app.moveZ > 1
        app.MovingSliceSlider.Limits = [1, app.moveZ];
        app.MovingSliceSlider.MajorTicks = [1, app.moveZ];
        app.MovingSliceSlider.MinorTicks = [];
    else
        app.MovingSliceSlider.Limits = [0, 1];
    end
    app.MovingSliceSlider.Value = 1;
    
    app.RegisterStatusEditField.Value = "Moving Image Loaded.";
    
    
    if ~isempty(app.fixLoc)
        FixedSliceSliderValueChanging(app, struct('Value', app.FixedSliceSlider.Value));
    end


        end

        % Value changing function: MovingSliceSlider
        function MovingSliceSliderValueChanging(app, event)
            app.movingSliderValue = round(event.Value);
            app.LocationEditField_3.Value = app.moveLoc(app.movingSliderValue);
            app.CoveredDepthEditField_2.Value = app.moveLoc(app.movingSliderValue) + app.moveThick;
            app.moveImage = imadjust(app.moveVol(:,:,app.movingSliderValue));
            imagesc(app.UIAxes13_2,app.moveImage);
            colormap(app.UIAxes13_2,'gray');
            xlim(app.UIAxes13_2,[0 ,app.moveN]);
            ylim(app.UIAxes13_2,[0, app.moveM]);
            
        end

        % Button pushed function: FixedImageButton
        function FixedImageButtonPushed(app, event)
            app.regFix = imadjust(app.fixedVol(:,:,app.fixedSliderValue));
            app.FixedImageButton.BackgroundColor = [0 1 0];
        end

        % Button pushed function: MovingImageButton
        function MovingImageButtonPushed(app, event)
            app.regMove  = imadjust(app.moveVol(:,:,app.movingSliderValue));
            app.regMove = imresize(app.regMove,[app.fixedM,app.fixedN]);
            app.MovingImageButton.BackgroundColor = [0 1 0];
        end

        % Button pushed function: ShowPairButton
        function ShowPairButtonPushed(app, event)
            imshowpair(app.regSegFix,app.regSegMove,'falsecolor','Scaling','independent','Parent',app.UIAxes13_3);
            app.FixedImageButton.BackgroundColor = [0 1 1];
            app.MovingImageButton.BackgroundColor = [0 1 1];
        end

        % Button pushed function: RegisterButton
        function RegisterButtonPushed(app, event)
            app.RegisterStatusEditField.Value = "Registering Images";
            pause(0.001);
            app.registerStruct = registerImages(app,app.regMove,app.regFix);
            imshowpair(app.regFix,app.registerStruct.RegisteredImage,'falsecolor','ColorChannel',[1 2 0],'Parent',app.UIAxes13_3);
            app.RegisterStatusEditField.Value = "Images Registered";
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
      
    if isempty(app.fixedVol)
        app.RegisterStatusEditField.Value = "No image loaded to filter!";
        return;
    end

    
    app.RegisterStatusEditField.Value = "Filtering Fixed Image (NLM)...";
    pause(0.01); 
    
    try
        
        for idx = 1:1:app.fixedZ
            
            patch = std2(app.fixedVol(:,:,idx));
            
            
            app.fixedVol(:,:,idx) = imnlmfilt(app.fixedVol(:,:,idx), ...
                'DegreeOfSmoothing', 0.5 * patch);
        end
        
        
        currentIdx = round(app.FixedSliceSlider.Value);
        
       
        if currentIdx < 1, currentIdx = 1; end
        if currentIdx > app.fixedZ, currentIdx = app.fixedZ; end
        
       
        displayImg = imadjust(mat2gray(app.fixedVol(:,:,currentIdx)));
        
        imagesc(app.UIAxes13, displayImg);
        colormap(app.UIAxes13, 'gray');
        
        
        xlim(app.UIAxes13, [0, app.fixedN]);
        ylim(app.UIAxes13, [0, app.fixedM]);

        
        app.RegisterStatusEditField.Value = "Filter Applied.";
        
    catch ME
        app.RegisterStatusEditField.Value = "Filter Error: " + ME.message;
    end

        end

        % Button pushed function: SendRegisteredImagetoSegmenterButton
        function SendRegisteredImagetoSegmenterButtonPushed(app, event)
            app.RegisterStatusEditField.Value = "Image is Sending Segmenter";
            pause(0.001);
            app.SegmenterImage = app.registerStruct.RegisteredImage;
            imagesc(app.UIAxes14,app.SegmenterImage);
            xlim(app.UIAxes14,[0 ,app.fixedN]);
            ylim(app.UIAxes14,[0, app.fixedM]);
            colormap(app.UIAxes14,'gray');
            app.RegisterStatusEditField.Value = "Creating Model and Embeddings";
            pause(0.001);

            app.RegisterStatusEditField.Value = "Image Sent";
        end

        % Button pushed function: SegmentButton
        function SegmentButtonPushed(app, event)
          
            [app.Cortex,app.CortexMask,app.Medulla,app.MedullaMask] = segmentCortexMedulla(app,app.SegmenterImage);
            app.imageSegmented = insertObjectMask(app.SegmenterImage,app.CortexMask,"MaskColor","yellow","Opacity",0.3);
            app.imageSegmented=insertObjectMask(app.imageSegmented,app.MedullaMask,"MaskColor","red","Opacity",0.3);
            
            imagesc(app.UIAxes14_2,app.imageSegmented);
            app.CortexROIFromSegmenterButton.Enable = "on";
            app.MedullaROIFromSegmenterButton.Enable = "on";
            app.DrawCortexGroundTruthButton.Enable = "on";


        end

        % Button pushed function: CortexROIFromSegmenterButton
        function CortexROIFromSegmenterButtonPushed(app, event)
         
    
    mask = app.CortexMask;
    app.segmentMask = app.CortexMask;
    
    
    checkedNodes = app.BValueTree.CheckedNodes;
    selectedB = [];
    activeIndices = []; 
    
    for i = 1:length(checkedNodes)
        data = checkedNodes(i).NodeData;
        if isnumeric(data)
            selectedB = [selectedB, data];
        end
    end
    
    
    for k = 1:length(selectedB)
        val = selectedB(k);
        [minDiff, bestIdx] = min(abs(app.bvals - val));
        if minDiff < 0.1
            activeIndices = [activeIndices, bestIdx];
        end
    end
    activeIndices = unique(sort(activeIndices));
    
    
    app.sigData = [];
    filteredBVals = [];
    
    for k = 1:length(activeIndices)
        origIdx = activeIndices(k);
        volIdx = origIdx + ((app.sliceNum - 1) * length(app.bvals));
        
        tempvol = app.vol(:,:,volIdx);
        meanData = mean(tempvol(mask));
        
        app.sigData = [app.sigData, meanData];
        filteredBVals = [filteredBVals, app.bvals(origIdx)];
    end
    
   
    if ~isempty(app.sigData)
        if app.sigData(1) ~= 0
            app.s_s0 = app.sigData ./ app.sigData(1);
        else
            app.s_s0 = zeros(size(app.sigData));
        end
    end

    
    plot(app.UIAxes2, filteredBVals, app.s_s0, '-o', 'LineWidth', 1.5);
    title(app.UIAxes2, 'Cortex Signal Data');
    xlabel(app.UIAxes2, 'B-Value');
    ylabel(app.UIAxes2, 'S / S0');
    grid(app.UIAxes2, 'on');

    
    rawImg = app.vol(:,:,(app.showBval)+((app.sliceNum-1)*length(app.bvals)));
   
    safeImg = imadjust(mat2gray(rawImg)); 
    
    
    overlayimage = insertObjectMask(safeImg, mask, "MaskColor", "magenta", "Opacity", 0.3);
    
    imagesc(app.UIAxes, overlayimage);
    xlim(app.UIAxes, [0, app.N]);
    ylim(app.UIAxes, [0, app.M]);
    title(app.UIAxes, 'IMAGE (Cortex ROI)');


        end

        % Value changing function: ImageNumberSpinner
        function ImageNumberSpinnerValueChanging(app, event)
            Value = event.Value;
            imagesc(app.PreviewAxes,app.Prevol(:,:,Value));
            colormap(app.PreviewAxes,'gray');
            xlim(app.PreviewAxes,[0,app.preN]);
            ylim(app.PreviewAxes,[0,app.preM]);
            
        end

        % Button pushed function: DrawROIButton_2
        function DrawROIButton_2Pushed(app, event)
       
    
    delete(findall(app.UIAxes13, 'Type', 'images.roi'));
    
    
    rawImg = app.fixedVol(:,:,app.fixedSliderValue);
    
    
    app.regSegFix = imadjust(mat2gray(rawImg));
    
   
    imagesc(app.UIAxes13, app.regSegFix);
    colormap(app.UIAxes13, "gray");
    xlim(app.UIAxes13, [0, app.fixedN]);
    ylim(app.UIAxes13, [0, app.fixedM]);
    
    app.segmentFixedRegion = drawrectangle(app.UIAxes13);
    bbox = app.segmentFixedRegion.Position;
    if isempty(app.medsam) 
        app.medsam = medicalSegmentAnythingModel;
    end
    
    bboxArea = bbox(3) * bbox(4);
    app.SegFixEmbd = extractEmbeddings(app.medsam, app.regSegFix);
    
    [app.segmentFixedMask, scores] = segmentObjectsFromEmbeddings(app.medsam, app.SegFixEmbd, size(app.regSegFix), 'BoundingBox', app.segmentFixedRegion.Position);
    
    confidenceScore = max(scores); 
    maskArea = sum(app.segmentFixedMask(:));
    fillRatio = maskArea / bboxArea;
    GACS = confidenceScore / fillRatio;
    app.segmentedFixed = insertObjectMask(app.regSegFix, app.segmentFixedMask, 'Color', 'cyan', 'Opacity', 0.4);
    
    imagesc(app.UIAxes13, app.segmentedFixed);
    
    
    title(app.UIAxes13, sprintf('Segmented Image (Confidence: %.4f,GACS: %.4f)', confidenceScore,GACS));

        end

        % Button pushed function: DrawROIButton_3
        function DrawROIButton_3Pushed(app, event)
           
    
    delete(findall(app.UIAxes13_2, 'Type', 'images.roi'));
    
   
    rawImg = app.moveVol(:,:,app.movingSliderValue);
    
    
    safeImg = imadjust(mat2gray(rawImg));
    
   
    app.regSegMove = imresize(safeImg, [app.fixedM, app.fixedN]);
    
    
    imagesc(app.UIAxes13_2, app.regSegMove);
    colormap(app.UIAxes13_2, "gray");
    xlim(app.UIAxes13_2, [0, app.fixedN]);
    ylim(app.UIAxes13_2, [0, app.fixedM]);
    
    app.segmentMovingRegion = drawrectangle(app.UIAxes13_2);
    bbox = app.segmentMovingRegion.Position;
    
    if isempty(app.medsam)
        app.medsam = medicalSegmentAnythingModel;
    end
    bboxArea = bbox(3) * bbox(4);
    app.SegMoveEmbd = extractEmbeddings(app.medsam, app.regSegMove);
    
    [app.segmentMoveMask, scores] = segmentObjectsFromEmbeddings(app.medsam, app.SegMoveEmbd, size(app.regSegMove), 'BoundingBox', app.segmentMovingRegion.Position);
    
    confidenceScore = max(scores);
    
    maskArea = sum(app.segmentFixedMask(:));
    fillRatio = maskArea / bboxArea;
    GACS = confidenceScore / fillRatio;
    app.segmentedMove = insertObjectMask(app.regSegMove, app.segmentMoveMask, 'Color', 'yellow', 'Opacity', 0.4);
    
    imagesc(app.UIAxes13_2, app.segmentedMove);
    
    title(app.UIAxes13_2, sprintf('Segmented Moving Image (Confidence: %.4f,GACS: %.4f)', confidenceScore,GACS));


        end

        % Button pushed function: FixedSegmentButton
        function FixedSegmentButtonPushed(app, event)
            app.regSegFix(~app.segmentFixedMask) = 0;
            imagesc(app.UIAxes13_3,app.regSegFix);
        end

        % Button pushed function: MovingSegmentButton
        function MovingSegmentButtonPushed(app, event)
            app.regSegMove(~app.segmentMoveMask) = 0;
            imagesc(app.UIAxes13_3,app.regSegMove);
        end

        % Button pushed function: SegmentGuidedRegisterButton
        function SegmentGuidedRegisterButtonPushed(app, event)
            app.RegisterStatusEditField.Value = "Registering Images";
            pause(0.001);


            app.registerStruct = registerImages(app, app.regSegMove, app.regSegFix);


            targetImg = app.regSegFix;

            resultImg = app.registerStruct.RegisteredImage;


            try
    
             metricScore = ssim(double(resultImg), double(targetImg));
             metricName = "SSIM";
            catch
    
             metricScore = corr2(double(resultImg), double(targetImg));
                 metricName = "Corr";
            end


imshowpair(app.regSegFix, app.registerStruct.RegisteredImage, 'falsecolor', 'ColorChannel', [1 2 0], 'Parent', app.UIAxes13_3);


app.RegisterStatusEditField.Value = sprintf("Images Registered (%s: %.4f)", metricName, metricScore);
        end

        % Button pushed function: MedullaROIFromSegmenterButton
        function MedullaROIFromSegmenterButtonPushed(app, event)
         
   
    mask = app.MedullaMask;
    app.segmentMask = app.MedullaMask;
    
  
    checkedNodes = app.BValueTree.CheckedNodes;
    selectedB = [];
    activeIndices = []; 
    
    for i = 1:length(checkedNodes)
        data = checkedNodes(i).NodeData;
        if isnumeric(data)
            selectedB = [selectedB, data];
        end
    end
    
    for k = 1:length(selectedB)
        val = selectedB(k);
        [minDiff, bestIdx] = min(abs(app.bvals - val));
        if minDiff < 0.1
            activeIndices = [activeIndices, bestIdx];
        end
    end
    activeIndices = unique(sort(activeIndices));
    
    app.sigData = [];
    filteredBVals = [];
    
    for k = 1:length(activeIndices)
        origIdx = activeIndices(k);
        volIdx = origIdx + ((app.sliceNum - 1) * length(app.bvals));
        
        tempvol = app.vol(:,:,volIdx);
        meanData = mean(tempvol(mask));
        
        app.sigData = [app.sigData, meanData];
        filteredBVals = [filteredBVals, app.bvals(origIdx)];
    end
    
    if ~isempty(app.sigData)
        if app.sigData(1) ~= 0
            app.s_s0 = app.sigData ./ app.sigData(1);
        else
            app.s_s0 = zeros(size(app.sigData));
        end
    end

   
    plot(app.UIAxes2, filteredBVals, app.s_s0, '-o', 'LineWidth', 1.5, 'Color', 'r');
    title(app.UIAxes2, 'Medulla Signal Data');
    xlabel(app.UIAxes2, 'B-Value');
    ylabel(app.UIAxes2, 'S / S0');
    grid(app.UIAxes2, 'on');

  
    rawImg = app.vol(:,:,(app.showBval)+((app.sliceNum-1)*length(app.bvals)));
    
   
    safeImg = imadjust(mat2gray(rawImg));
    
    overlayimage = insertObjectMask(safeImg, mask, "MaskColor", "cyan", "Opacity", 0.3);
    
    imagesc(app.UIAxes, overlayimage);
    xlim(app.UIAxes, [0, app.N]);
    ylim(app.UIAxes, [0, app.M]);
    title(app.UIAxes, 'IMAGE (Medulla ROI)');

        end

        % Button pushed function: DrawCortexGroundTruthButton
        function DrawCortexGroundTruthButtonPushed(app, event)
            app.CortexGtRoi = drawassisted(findobj(app.UIAxes14,'Type','Image'));
            app.CortexGt = createMask(app.CortexGtRoi);
            temp = app.SegmenterImage;
            tempDraw = app.SegmenterImage;
            temp(~app.CortexGt) = 0;
            app.MedullaGt = imsubtract(app.SegmenterImage,temp);
            app.MedullaGt = imbinarize(app.MedullaGt);
            app.MedullaGt = bwareaopen(app.MedullaGt,20);
            app.MedullaGt = imfill(app.MedullaGt,"holes");
            app.drawSegmented = insertObjectMask(tempDraw,app.CortexGt,"MaskColor","magenta","Opacity",0.3);
            app.drawSegmented=insertObjectMask(app.drawSegmented,app.MedullaMask,"MaskColor","green","Opacity",0.3);
            imagesc(app.UIAxes14,app.drawSegmented);
        end

        % Button pushed function: CalculateSimilarityCoefficientsButton
        function CalculateSimilarityCoefficientsButtonPushed(app, event)
            app.JaccardCortex = jaccard(app.CortexMask,app.CortexGt);
            app.DiceCortex = dice(app.CortexMask,app.CortexGt);
            app.JaccardMedulla = jaccard(app.MedullaMask,app.MedullaGt);
            app.DiceMedulla = dice(app.MedullaMask,app.MedullaGt);
            app.JaccardCortexEditField.Value = app.JaccardCortex;
            app.DICECortexEditField.Value = app.DiceCortex;
            app.JaccardMedullaEditField.Value = app.JaccardMedulla;
            app.DICEMedullaEditField.Value = app.DiceMedulla;
            figure(1);
            imshowpair(app.MedullaMask,app.MedullaGt,'falsecolor','Scaling','independent');
            figure(2);
            imshowpair(app.CortexMask,app.CortexGt,'falsecolor','Scaling','independent');
        end

        % Button pushed function: CopyDataButton
        function CopyDataButtonPushed(app, event)
            fields = {
                app.ADCEditField;
                app.DpEditField;
                app.DtEditField;
                app.pFEditField;
                app.DfastEditField;
                app.DinterEditField;
                app.DslowEditField;
                app.pFfastEditField;
                app.pFinterEditField;
                app.pFslowEditField;
                }
    values = [];

    for i = 1:numel(fields)
        fieldVal = fields{i}.Value;

        
        if ischar(fieldVal) || isstring(fieldVal)
            val = str2double(fieldVal);
        else
            val = fieldVal;
        end

      
        if ~isnan(val) && val ~= 0
            values(end+1) = val; 
        end
    end

    
    if ~isempty(values)
        clipboardStr = strjoin(arrayfun(@(x) sprintf('%.5f', x), values, 'UniformOutput', false), '\t');
        clipboard('copy', clipboardStr);
    end
       

        end

        % Callback function
        function B_ValueListBoxValueChanged(app, event)
    
    try
        
        selectedB = app.B_ValueListBox.Value;
        
        
        if isempty(selectedB)
            return;
        end

        idx = [];
        for k = 1:length(selectedB)
            val = selectedB(k);
            [minDiff, bestIdx] = min(abs(app.bvals - val));
            if minDiff < 0.1
                idx = [idx, bestIdx];
            end
        end
        idx = unique(sort(idx));
        
       
        if ~isempty(idx)
            b_subset = app.bvals(idx);
            s_subset = app.s_s0(idx);
            
           
            plot(app.UIAxes2, b_subset, s_subset, '-o', 'LineWidth', 1.5, 'Color', [0 0.4470 0.7410]);
            title(app.UIAxes2, 'Signal Data (Filtered)');
            xlabel(app.UIAxes2, 'B-Value');
            ylabel(app.UIAxes2, 'S / S0');
            grid(app.UIAxes2, 'on');
            
            
            if length(app.bvals) > 1
                 xlim(app.UIAxes2, [min(app.bvals)-10, max(app.bvals)+50]);
                 ylim(app.UIAxes2, [-0.1, 1.1]);
            end
        end
        
    catch ME
        disp("ListBox Update Error: " + ME.message);
    end


        end

        % Callback function: BValueTree
        function BValueTreeCheckedNodesChanged(app, event)
         try
       
    hasMultiROI = false;
    if ~isempty(app.MultiROIStorage)
        for i = 1:length(app.MultiROIStorage)
            if isvalid(app.MultiROIStorage(i))
                hasMultiROI = true;
                break;
            end
        end
    end
    
   
    if hasMultiROI
        calculateMultiROI(app);
    else
        calculateAndPlotROI(app);
    end
    
        
    catch ME
        disp("Tree Update Error: " + ME.message);
        end
            
        end

        % Button pushed function: UndoFilterButton
        function UndoFilterButtonPushed(app, event)
            
    if ~isempty(app.vol_backup)
        app.StateField.Value = "Restoring Original Data...";
        pause(0.1);
        
        
        app.vol = app.vol_backup;
        
       
        app.vol_backup = [];
        
        
        currentIdx = (app.showBval) + ((app.sliceNum - 1) * length(app.bvals));
        if currentIdx <= size(app.vol, 3)
            currentImg = app.vol(:,:,currentIdx);
            if max(currentImg(:)) > 0
                safeImg = mat2gray(currentImg);
            else
                safeImg = currentImg;
            end
            imagesc(app.UIAxes, imadjust(safeImg));
            colormap(app.UIAxes, "gray");
            xlim(app.UIAxes, [0, app.N]);
            ylim(app.UIAxes, [0, app.M]);
        end
        
       
        app.UndoFilterButton.Enable = 'off';
        
        app.StateField.Value = "Original Data Restored.";
        app.StateField.BackgroundColor = [0.5, 0.5, 1.0]; % Mavi
    else
        app.StateField.Value = "Nothing to Undo.";
    end
        end

        % Button pushed function: ResetButtonADC
        function ResetButtonADCPushed(app, event)
            app.StartPointEditFieldADC.Value = 0.001;
app.LowerLimitEditFieldADC.Value = 0;
app.UpperLimitEditFieldADC.Value = 0.005;
        end

        % Button pushed function: ResetButtonIVIM
        function ResetButtonIVIMPushed(app, event)
            app.StartPointEditField_f.Value = 0.2;
app.LowerLimitEditField_f.Value = 0;
app.UpperLimitEditField_f.Value = 1;


app.StartPointEditField_Dp.Value = 0.05;
app.LowerLimitEditField_Dp.Value = 0.005;
app.UpperLimitEditField_Dp.Value = 0.5;


app.StartPointEditField_Dt.Value = 0.0015;
app.LowerLimitEditField_Dt.Value = 0;
app.UpperLimitEditField_Dt.Value = 0.005;

app.BThresholdEditField.Value = 200;
        end

        % Button pushed function: ResetButtonTri
        function ResetButtonTriPushed(app, event)
            app.StartPointEditField_f1.Value = 0.1; 
app.StartPointEditField_f2.Value = 0.1;

app.StartPointEditField_D1.Value = 0.01;  
app.StartPointEditField_D2.Value = 0.003; 
app.StartPointEditField_D3.Value = 0.0005; 

app.LowerLimitEditField_f1.Value = 0;
app.LowerLimitEditField_f2.Value = 0;
app.LowerLimitEditField_D1.Value = 0.005;
app.LowerLimitEditField_D2.Value = 0.0005;
app.LowerLimitEditField_D3.Value = 0.0001;

app.UpperLimitEditField_f1.Value = 0.8;
app.UpperLimitEditField_f2.Value = 0.8;
app.UpperLimitEditField_D1.Value =  0.1;
app.UpperLimitEditField_D2.Value = 0.02;
app.UpperLimitEditField_D3.Value = 0.005;
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            value = app.MethodDropDown.Value;
            if value == "ADC"
                cla(app.UIAxes_dp);
                cla(app.UIAxes3_f);
                app.UIAxes_dp.Visible = 'off';
                app.UIAxes3_f.Visible ='off';
               
                imagesc(app.UIAxes3_dt, app.ADCmap);
                colormap(app.UIAxes3_dt,"jet"); colorbar(app.UIAxes3_dt);
                xlim(app.UIAxes3_dt,[0, app.N]); ylim(app.UIAxes3_dt,[0, app.M]);
                title(app.UIAxes3_dt,"ADC");
                
            end
            if value == "Free"
                    app.UIAxes_dp.Visible = 'on';
                    app.UIAxes3_f.Visible ='on';
                    imagesc(app.UIAxes_dp,app.Dpmap);
                    colormap(app.UIAxes_dp,"jet");colorbar(app.UIAxes_dp);
                    xlim(app.UIAxes_dp,[0, app.N]); ylim(app.UIAxes_dp,[0, app.M]);

                    imagesc(app.UIAxes3_dt,app.Dtmap);
                    colormap(app.UIAxes3_dt,"jet");colorbar(app.UIAxes3_dt);
                    xlim(app.UIAxes3_dt,[0, app.N]); ylim(app.UIAxes3_dt,[0, app.M]);
                    title(app.UIAxes3_dt,"DT");

                    imagesc(app.UIAxes3_f,app.pFmap);
                    colormap(app.UIAxes3_f,"jet");colorbar(app.UIAxes3_f);
                    xlim(app.UIAxes3_f,[0, app.N]); ylim(app.UIAxes3_f,[0, app.M]);
            end
            if value == "Segmented"
                    app.UIAxes_dp.Visible = 'on';
                    app.UIAxes3_f.Visible ='on';
                    imagesc(app.UIAxes_dp,app.DpSegmap);
                    colormap(app.UIAxes_dp,"jet");colorbar(app.UIAxes_dp);
                    xlim(app.UIAxes_dp,[0, app.N]); ylim(app.UIAxes_dp,[0, app.M]);

                    imagesc(app.UIAxes3_dt,app.DtSegmap);
                    colormap(app.UIAxes3_dt,"jet");colorbar(app.UIAxes3_dt);
                    xlim(app.UIAxes3_dt,[0, app.N]); ylim(app.UIAxes3_dt,[0, app.M]);
                    title(app.UIAxes3_dt,"DT");

                    imagesc(app.UIAxes3_f,app.pFSegmap);
                    colormap(app.UIAxes3_f,"jet");colorbar(app.UIAxes3_f);
                    xlim(app.UIAxes3_f,[0, app.N]); ylim(app.UIAxes3_f,[0, app.M]);
            end
            if value == "Bayesian"
                app.UIAxes_dp.Visible = 'on';
                    app.UIAxes3_f.Visible ='on';
                    imagesc(app.UIAxes_dp,app.dPbayesMap);
                    colormap(app.UIAxes_dp,"jet");colorbar(app.UIAxes_dp);
                    xlim(app.UIAxes_dp,[0, app.N]); ylim(app.UIAxes_dp,[0, app.M]);

                    imagesc(app.UIAxes3_dt,app.dTbayesMap);
                    colormap(app.UIAxes3_dt,"jet");colorbar(app.UIAxes3_dt);
                    xlim(app.UIAxes3_dt,[0, app.N]); ylim(app.UIAxes3_dt,[0, app.M]);
                    title(app.UIAxes3_dt,"DT");

                    imagesc(app.UIAxes3_f,app.pFbayesMap);
                    colormap(app.UIAxes3_f,"jet");colorbar(app.UIAxes3_f);
                    xlim(app.UIAxes3_f,[0, app.N]); ylim(app.UIAxes3_f,[0, app.M]);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DiffusonCalculatorUIFigure = uifigure('Visible', 'off');
            app.DiffusonCalculatorUIFigure.Name = 'Diffuson Calculator ';
            app.DiffusonCalculatorUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @DiffusonCalculatorUIFigureWindowScrollWheel, true);
            app.TabGroup = uitabgroup(app.DiffusonCalculatorUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.CalculatorTab = uitab(app.TabGroup);
            app.CalculatorTab.Title = 'Calculator';
            app.FittingGraphicsPanel = uipanel(app.CalculatorTab);
            app.FittingGraphicsPanel.Title = 'Fitting Graphics';
            app.UIAxes2 = uiaxes(app.FittingGraphicsPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.RSquareEditFieldLabel = uilabel(app.FittingGraphicsPanel);
            app.RSquareEditFieldLabel.Text = 'R-Square';
            app.RSquareEditField = uieditfield(app.FittingGraphicsPanel, 'numeric');
            app.RSquareEditField.ValueDisplayFormat = '%.4f';
            app.ImageViewPanel = uipanel(app.CalculatorTab);
            app.ImageViewPanel.Title = 'Image View';
            app.UIAxes = uiaxes(app.ImageViewPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.OptionsPanel = uipanel(app.CalculatorTab);
            app.OptionsPanel.Title = 'Options';
            app.DtEditFieldLabel = uilabel(app.OptionsPanel);
            app.DtEditFieldLabel.Text = 'Dt';
            app.DtEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.DtEditField.ValueDisplayFormat = '%.8f';
            app.DpEditFieldLabel = uilabel(app.OptionsPanel);
            app.DpEditFieldLabel.Text = 'Dp';
            app.DpEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.DpEditField.ValueDisplayFormat = '%.8f';
            app.pFEditFieldLabel = uilabel(app.OptionsPanel);
            app.pFEditFieldLabel.Text = 'pF';
            app.pFEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.pFEditField.ValueDisplayFormat = '%.8f';
            app.ADCEditFieldLabel = uilabel(app.OptionsPanel);
            app.ADCEditFieldLabel.Text = 'ADC';
            app.ADCEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.ADCEditField.ValueDisplayFormat = '%.8f';
            app.CalculateButton = uibutton(app.OptionsPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.CalculateButton.Icon = fullfile(pathToMLAPP, 'icons', 'calculate.png');
            app.ROIStyleDropDownLabel = uilabel(app.OptionsPanel);
            app.ROIStyleDropDownLabel.Text = 'ROI Style';
            app.ROIStyleDropDown = uidropdown(app.OptionsPanel);
            app.ROIStyleDropDown.Items = {'Rectangle', 'Polygon', 'Freehand', 'Assisted'};
            app.ROIStyleDropDown.Value = 'Rectangle';
            app.DrawROIButton = uibutton(app.OptionsPanel, 'state');
            app.DrawROIButton.ValueChangedFcn = createCallbackFcn(app, @DrawROIButtonValueChanged, true);
            app.DrawROIButton.Text = 'DrawROI';
            app.DrawROIButton.Icon = fullfile(pathToMLAPP, 'icons', 'draw.png');
            app.DrawMultipleROIButton = uibutton(app.OptionsPanel, 'push');
            app.DrawMultipleROIButton.ButtonPushedFcn = createCallbackFcn(app, @DrawMultipleROIButtonPushed, true);
            app.DrawMultipleROIButton.Text = 'Draw Multiple ROI';
            app.DrawMultipleROIButton.Icon = fullfile(pathToMLAPP, 'icons', 'multidraw.png');
            app.MultiRoiSwitch = uiswitch(app.OptionsPanel, 'slider');
            app.DfastEditFieldLabel = uilabel(app.OptionsPanel);
            app.DfastEditFieldLabel.Text = 'Dfast';
            app.DfastEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.DfastEditField.ValueDisplayFormat = '%.8f';
            app.DinterEditFieldLabel = uilabel(app.OptionsPanel);
            app.DinterEditFieldLabel.Text = 'Dinter';
            app.DinterEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.DinterEditField.ValueDisplayFormat = '%.8f';
            app.DslowEditField_5Label = uilabel(app.OptionsPanel);
            app.DslowEditField_5Label.Text = 'Dslow';
            app.DslowEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.DslowEditField.ValueDisplayFormat = '%.8f';
            app.ImportFromLastDatabaseButton = uibutton(app.OptionsPanel, 'push');
            app.ImportFromLastDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFromLastDatabaseButtonPushed, true);
            app.ImportFromLastDatabaseButton.Text = 'Import From Last Database';
            app.pFfastEditFieldLabel = uilabel(app.OptionsPanel);
            app.pFfastEditFieldLabel.Text = 'pFfast';
            app.pFfastEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.pFfastEditField.ValueDisplayFormat = '%.8f';
            app.pFinterEditFieldLabel = uilabel(app.OptionsPanel);
            app.pFinterEditFieldLabel.Text = 'pFinter';
            app.pFinterEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.pFinterEditField.ValueDisplayFormat = '%.8f';
            app.pFslowEditFieldLabel = uilabel(app.OptionsPanel);
            app.pFslowEditFieldLabel.Text = 'pFslow';
            app.pFslowEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.pFslowEditField.ValueDisplayFormat = '%.8f';
            app.FilterDWIBetaButton = uibutton(app.OptionsPanel, 'push');
            app.FilterDWIBetaButton.ButtonPushedFcn = createCallbackFcn(app, @FilterDWIBetaButtonPushed, true);
            app.FilterDWIBetaButton.Text = 'Filter DWI (Beta)';
            app.FilterDWIBetaButton.Icon = fullfile(pathToMLAPP, 'icons', 'filter.png');
            app.UndoFilterButton = uibutton(app.OptionsPanel, 'push');
            app.UndoFilterButton.ButtonPushedFcn = createCallbackFcn(app, @UndoFilterButtonPushed, true);
            app.UndoFilterButton.Text = 'Undo Filter';
            app.LoadDICOMButton = uibutton(app.CalculatorTab, 'push');
            app.LoadDICOMButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDICOMButtonPushed, true);
            app.LoadDICOMButton.Text = 'Load DICOM';
            app.LoadDICOMButton.Icon = fullfile(pathToMLAPP, 'icons', 'import.png');
            app.AlgorithmButtonGroup = uibuttongroup(app.CalculatorTab);
            app.AlgorithmButtonGroup.Title = 'Algorithm';
            app.MonoExpButton = uiradiobutton(app.AlgorithmButtonGroup);
            app.MonoExpButton.Text = 'MonoExp';
            app.MonoExpButton.Value = true;
            app.BiexpFreeButton = uiradiobutton(app.AlgorithmButtonGroup);
            app.BiexpFreeButton.Text = 'BiexpFree';
            app.BiExpSegButton = uiradiobutton(app.AlgorithmButtonGroup);
            app.BiExpSegButton.Text = 'BiExpSeg';
            app.BayesianButton = uiradiobutton(app.AlgorithmButtonGroup);
            app.BayesianButton.Text = 'Bayesian';
            app.TriExpButton = uiradiobutton(app.AlgorithmButtonGroup);
            app.TriExpButton.Text = 'TriExp';
            app.EditField = uieditfield(app.CalculatorTab, 'numeric');
            app.EditField_2 = uieditfield(app.CalculatorTab, 'numeric');
            app.GenerateMapsButton = uibutton(app.CalculatorTab, 'push');
            app.GenerateMapsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMapsButtonPushed, true);
            app.GenerateMapsButton.Text = 'Generate Maps';
            app.GenerateMapsButton.Icon = fullfile(pathToMLAPP, 'icons', 'generate.png');
            app.BValueKnobLabel = uilabel(app.CalculatorTab);
            app.BValueKnobLabel.Text = 'B-Value';
            app.BValueKnob = uiknob(app.CalculatorTab, 'discrete');
            app.BValueKnob.ValueChangedFcn = createCallbackFcn(app, @BValueKnobValueChanged, true);
            app.SliceKnobLabel = uilabel(app.CalculatorTab);
            app.SliceKnobLabel.Text = 'Slice';
            app.SliceKnob = uiknob(app.CalculatorTab, 'discrete');
            app.SliceKnob.ValueChangedFcn = createCallbackFcn(app, @SliceKnobValueChanged, true);
            app.KernelSizeEditFieldLabel = uilabel(app.CalculatorTab);
            app.KernelSizeEditFieldLabel.Text = 'Kernel Size';
            app.KernelSizeEditField = uieditfield(app.CalculatorTab, 'numeric');
            app.KernelSizeEditField.Value = 4;
            app.StateField = uieditfield(app.CalculatorTab, 'text');
            app.mapRoiSwitch = uiswitch(app.CalculatorTab, 'slider');
            app.mapRoiSwitch.Items = {'SingleROI', 'MultiROI'};
            app.mapRoiSwitch.ValueChangedFcn = createCallbackFcn(app, @mapRoiSwitchValueChanged, true);
            app.mapRoiSwitch.Value = 'SingleROI';
            app.SmoothingDegreeEditFieldLabel = uilabel(app.CalculatorTab);
            app.SmoothingDegreeEditFieldLabel.Text = 'Smoothing Degree';
            app.SmoothingDegreeEditField = uieditfield(app.CalculatorTab, 'numeric');
            app.LocationEditFieldLabel = uilabel(app.CalculatorTab);
            app.LocationEditFieldLabel.Text = 'Location';
            app.LocationEditField = uieditfield(app.CalculatorTab, 'numeric');
            app.CortexROIFromSegmenterButton = uibutton(app.CalculatorTab, 'push');
            app.CortexROIFromSegmenterButton.ButtonPushedFcn = createCallbackFcn(app, @CortexROIFromSegmenterButtonPushed, true);
            app.CortexROIFromSegmenterButton.Enable = 'off';
            app.CortexROIFromSegmenterButton.Text = 'Cortex ROI From Segmenter';
            app.MedullaROIFromSegmenterButton = uibutton(app.CalculatorTab, 'push');
            app.MedullaROIFromSegmenterButton.ButtonPushedFcn = createCallbackFcn(app, @MedullaROIFromSegmenterButtonPushed, true);
            app.MedullaROIFromSegmenterButton.Enable = 'off';
            app.MedullaROIFromSegmenterButton.Text = 'Medulla ROI From Segmenter';
            app.CopyDataButton = uibutton(app.CalculatorTab, 'push');
            app.CopyDataButton.ButtonPushedFcn = createCallbackFcn(app, @CopyDataButtonPushed, true);
            app.CopyDataButton.Text = 'Copy Data';
            app.BValueTree = uitree(app.CalculatorTab, 'checkbox');
            app.Node = uitreenode(app.BValueTree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            % Assign Checked Nodes
            app.BValueTree.CheckedNodesChangedFcn = createCallbackFcn(app, @BValueTreeCheckedNodesChanged, true);
            app.DicomTab = uitab(app.TabGroup);
            app.DicomTab.Title = 'Dicom';
            app.PreviewAxes = uiaxes(app.DicomTab);
            title(app.PreviewAxes, 'Title')
            xlabel(app.PreviewAxes, 'X')
            ylabel(app.PreviewAxes, 'Y')
            zlabel(app.PreviewAxes, 'Z')
            app.SequencesPanel = uipanel(app.DicomTab);
            app.SequencesPanel.Title = 'Sequences';
            app.SequencesListBoxLabel = uilabel(app.SequencesPanel);
            app.SequencesListBoxLabel.Text = 'Sequences';
            app.SequencesListBox = uilistbox(app.SequencesPanel);
            app.SequencesListBox.ValueChangedFcn = createCallbackFcn(app, @SequencesListBoxValueChanged, true);
            app.LoadtoCalculatorButton = uibutton(app.SequencesPanel, 'push');
            app.LoadtoCalculatorButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtoCalculatorButtonPushed, true);
            app.LoadtoCalculatorButton.Text = 'Load to Calculator';
            app.LoadtoCalculatorButton.Icon = fullfile(pathToMLAPP, 'icons', 'send.png');
            app.LoadtoViewerButton = uibutton(app.SequencesPanel, 'push');
            app.LoadtoViewerButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtoViewerButtonPushed, true);
            app.LoadtoViewerButton.Text = 'Load to Viewer';
            app.LoadtoViewerButton.Icon = fullfile(pathToMLAPP, 'icons', 'send.png');
            app.FramesEditFieldLabel = uilabel(app.SequencesPanel);
            app.FramesEditFieldLabel.Text = 'Frames';
            app.FramesEditField = uieditfield(app.SequencesPanel, 'numeric');
            app.LoadtoBOLDPcsButton = uibutton(app.SequencesPanel, 'push');
            app.LoadtoBOLDPcsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtoBOLDPcsButtonPushed, true);
            app.LoadtoBOLDPcsButton.Text = 'Load to BOLD Pcs.';
            app.LoadtoBOLDPcsButton.Icon = fullfile(pathToMLAPP, 'icons', 'send.png');
            app.TimeEditFieldLabel = uilabel(app.SequencesPanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.SequencesPanel, 'text');
            app.SaveDICOMDatabaseButton = uibutton(app.SequencesPanel, 'push');
            app.SaveDICOMDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDICOMDatabaseButtonPushed, true);
            app.SaveDICOMDatabaseButton.Text = 'Save DICOM Database';
            app.SaveDICOMDatabaseButton.Icon = fullfile(pathToMLAPP, 'icons', 'import.png');
            app.LoadDICOMDatabaseButton = uibutton(app.SequencesPanel, 'push');
            app.LoadDICOMDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDICOMDatabaseButtonPushed, true);
            app.LoadDICOMDatabaseButton.Text = 'Load DICOM Database';
            app.LoadDICOMDatabaseButton.Icon = fullfile(pathToMLAPP, 'icons', 'import.png');
            app.PatientsPanel = uipanel(app.DicomTab);
            app.PatientsPanel.Title = 'Patients';
            app.PatientsListBoxLabel = uilabel(app.PatientsPanel);
            app.PatientsListBoxLabel.Text = 'Patients';
            app.PatientsListBox = uilistbox(app.PatientsPanel);
            app.PatientsListBox.ValueChangedFcn = createCallbackFcn(app, @PatientsListBoxValueChanged, true);
            app.DicomState = uieditfield(app.DicomTab, 'text');
            app.ImageNumberSpinnerLabel = uilabel(app.DicomTab);
            app.ImageNumberSpinnerLabel.Text = 'Image Number';
            app.ImageNumberSpinner = uispinner(app.DicomTab);
            app.ImageNumberSpinner.ValueChangingFcn = createCallbackFcn(app, @ImageNumberSpinnerValueChanging, true);
            app.ViewerTab = uitab(app.TabGroup);
            app.ViewerTab.Title = 'Viewer';
            app.ImageViewPanel_2 = uipanel(app.ViewerTab);
            app.ImageViewPanel_2.Title = 'Image View';
            app.UIAxes8 = uiaxes(app.ImageViewPanel_2);
            title(app.UIAxes8, 'IMAGE')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.ViewSliderLabel = uilabel(app.ImageViewPanel_2);
            app.ViewSliderLabel.Text = 'ViewSlider';
            app.ViewSlider = uislider(app.ImageViewPanel_2);
            app.ViewSlider.Limits = [1 100];
            app.ViewSlider.ValueChangingFcn = createCallbackFcn(app, @ViewSliderValueChanging, true);
            app.ViewSlider.Value = 1;
            app.InstanceNumberEditFieldLabel = uilabel(app.ImageViewPanel_2);
            app.InstanceNumberEditFieldLabel.Text = 'Instance Number';
            app.InstanceNumberEditField = uieditfield(app.ImageViewPanel_2, 'numeric');
            app.ColormapDropDownLabel = uilabel(app.ImageViewPanel_2);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.ImageViewPanel_2);
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ContrastSliderLabel = uilabel(app.ImageViewPanel_2);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.ImageViewPanel_2, 'range');
            app.ContrastSlider.Limits = [0 5];
            app.ContrastSlider.ValueChangingFcn = createCallbackFcn(app, @ContrastSliderValueChanging, true);
            app.ContrastSlider.Value = [0 2.5];
            app.BritghtnessSliderLabel = uilabel(app.ImageViewPanel_2);
            app.BritghtnessSliderLabel.Text = 'Britghtness';
            app.BritghtnessSlider = uislider(app.ImageViewPanel_2);
            app.BritghtnessSlider.Limits = [-1 1];
            app.BritghtnessSlider.ValueChangingFcn = createCallbackFcn(app, @BritghtnessSliderValueChanging, true);
            app.MapsTab = uitab(app.TabGroup);
            app.MapsTab.Title = 'Maps';
            app.DWIMAPSPanel = uipanel(app.MapsTab);
            app.DWIMAPSPanel.Title = 'DWI MAPS';
            app.UIAxes_dp = uiaxes(app.DWIMAPSPanel);
            title(app.UIAxes_dp, 'DP ')
            xlabel(app.UIAxes_dp, 'X')
            ylabel(app.UIAxes_dp, 'Y')
            zlabel(app.UIAxes_dp, 'Z')
            app.UIAxes3_dt = uiaxes(app.DWIMAPSPanel);
            title(app.UIAxes3_dt, 'DT ')
            xlabel(app.UIAxes3_dt, 'X')
            ylabel(app.UIAxes3_dt, 'Y')
            zlabel(app.UIAxes3_dt, 'Z')
            app.UIAxes3_f = uiaxes(app.DWIMAPSPanel);
            title(app.UIAxes3_f, 'pF ')
            xlabel(app.UIAxes3_f, 'X')
            ylabel(app.UIAxes3_f, 'Y')
            zlabel(app.UIAxes3_f, 'Z')
            app.MethodLabel = uilabel(app.DWIMAPSPanel);
            app.MethodLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.DWIMAPSPanel);
            app.MethodDropDown.Items = {'ADC', 'Free', 'Segmented', 'Bayesian'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'ADC';
            app.TriExpMapsPanel = uipanel(app.MapsTab);
            app.TriExpMapsPanel.Title = 'Tri-Exp Maps';
            app.UIAxesD1 = uiaxes(app.TriExpMapsPanel);
            title(app.UIAxesD1, 'Dfast')
            xlabel(app.UIAxesD1, 'X')
            ylabel(app.UIAxesD1, 'Y')
            zlabel(app.UIAxesD1, 'Z')
            app.UIAxesD2 = uiaxes(app.TriExpMapsPanel);
            title(app.UIAxesD2, 'Dinter')
            xlabel(app.UIAxesD2, 'X')
            ylabel(app.UIAxesD2, 'Y')
            zlabel(app.UIAxesD2, 'Z')
            app.UIAxesD3 = uiaxes(app.TriExpMapsPanel);
            title(app.UIAxesD3, 'Dslow')
            xlabel(app.UIAxesD3, 'X')
            ylabel(app.UIAxesD3, 'Y')
            zlabel(app.UIAxesD3, 'Z')
            app.UIAxesf1 = uiaxes(app.TriExpMapsPanel);
            title(app.UIAxesf1, 'FFast')
            xlabel(app.UIAxesf1, 'X')
            ylabel(app.UIAxesf1, 'Y')
            zlabel(app.UIAxesf1, 'Z')
            app.UIAxesf2 = uiaxes(app.TriExpMapsPanel);
            title(app.UIAxesf2, 'Finter')
            xlabel(app.UIAxesf2, 'X')
            ylabel(app.UIAxesf2, 'Y')
            zlabel(app.UIAxesf2, 'Z')
            app.UIAxesf3 = uiaxes(app.TriExpMapsPanel);
            title(app.UIAxesf3, 'Fslow')
            xlabel(app.UIAxesf3, 'X')
            ylabel(app.UIAxesf3, 'Y')
            zlabel(app.UIAxesf3, 'Z')
            app.SaveMapsButton = uibutton(app.TriExpMapsPanel, 'push');
            app.SaveMapsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMapsButtonPushed, true);
            app.SaveMapsButton.Text = 'Save Maps';
            app.ColorMapsDropDownLabel = uilabel(app.TriExpMapsPanel);
            app.ColorMapsDropDownLabel.Text = 'Color Maps';
            app.ColorMapsDropDown = uidropdown(app.TriExpMapsPanel);
            app.ColorMapsDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorMapsDropDownValueChanged, true);
            app.BOLD_Proceesor = uitab(app.TabGroup);
            app.BOLD_Proceesor.Title = 'BOLD Processor';
            app.R2ImagesPanel = uipanel(app.BOLD_Proceesor);
            app.R2ImagesPanel.Title = 'R2*Images';
            app.UIAxes10 = uiaxes(app.R2ImagesPanel);
            title(app.UIAxes10, 'Title')
            xlabel(app.UIAxes10, 'X')
            ylabel(app.UIAxes10, 'Y')
            zlabel(app.UIAxes10, 'Z')
            app.R2MapandFittingPanel = uipanel(app.BOLD_Proceesor);
            app.R2MapandFittingPanel.Title = 'R2* Map and Fitting';
            app.UIAxes11 = uiaxes(app.R2MapandFittingPanel);
            title(app.UIAxes11, 'Title')
            xlabel(app.UIAxes11, 'X')
            ylabel(app.UIAxes11, 'Y')
            zlabel(app.UIAxes11, 'Z')
            app.UIAxes12 = uiaxes(app.R2MapandFittingPanel);
            title(app.UIAxes12, 'Title')
            xlabel(app.UIAxes12, 'X')
            ylabel(app.UIAxes12, 'Y')
            zlabel(app.UIAxes12, 'Z')
            app.MeanR2msec1EditFieldLabel = uilabel(app.R2MapandFittingPanel);
            app.MeanR2msec1EditFieldLabel.Text = 'Mean R2*(msec^-1)';
            app.MeanR2msec1EditField = uieditfield(app.R2MapandFittingPanel, 'numeric');
            app.TEKnobLabel = uilabel(app.BOLD_Proceesor);
            app.TEKnobLabel.Text = 'TE';
            app.TEKnob = uiknob(app.BOLD_Proceesor, 'discrete');
            app.TEKnob.ValueChangedFcn = createCallbackFcn(app, @TEKnobValueChanged, true);
            app.BoldSliceLabel = uilabel(app.BOLD_Proceesor);
            app.BoldSliceLabel.Text = 'Bold Slice';
            app.BoldSliceKnob = uiknob(app.BOLD_Proceesor, 'discrete');
            app.BoldSliceKnob.ValueChangedFcn = createCallbackFcn(app, @BoldSliceKnobValueChanged, true);
            app.GenerateR2MapButton = uibutton(app.BOLD_Proceesor, 'push');
            app.GenerateR2MapButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateR2MapButtonPushed, true);
            app.GenerateR2MapButton.Text = 'Generate R2* Map';
            app.BoldState = uieditfield(app.BOLD_Proceesor, 'text');
            app.DrawROIforFitButton = uibutton(app.BOLD_Proceesor, 'state');
            app.DrawROIforFitButton.ValueChangedFcn = createCallbackFcn(app, @DrawROIforFitButtonValueChanged, true);
            app.DrawROIforFitButton.Text = 'Draw ROI for Fit';
            app.RegisterTab = uitab(app.TabGroup);
            app.RegisterTab.Title = 'Register';
            app.Panel = uipanel(app.RegisterTab);
            app.Panel.Title = 'Panel';
            app.UIAxes13 = uiaxes(app.Panel);
            title(app.UIAxes13, 'Title')
            xlabel(app.UIAxes13, 'X')
            ylabel(app.UIAxes13, 'Y')
            zlabel(app.UIAxes13, 'Z')
            app.UIAxes13_2 = uiaxes(app.Panel);
            title(app.UIAxes13_2, 'Title')
            xlabel(app.UIAxes13_2, 'X')
            ylabel(app.UIAxes13_2, 'Y')
            zlabel(app.UIAxes13_2, 'Z')
            app.UIAxes13_3 = uiaxes(app.Panel);
            title(app.UIAxes13_3, 'Title')
            xlabel(app.UIAxes13_3, 'X')
            ylabel(app.UIAxes13_3, 'Y')
            zlabel(app.UIAxes13_3, 'Z')
            app.FixedListBoxLabel = uilabel(app.Panel);
            app.FixedListBoxLabel.Text = 'Fixed';
            app.FixedListBox = uilistbox(app.Panel);
            app.FixedListBox.ValueChangedFcn = createCallbackFcn(app, @FixedListBoxValueChanged, true);
            app.FixedImageButton = uibutton(app.Panel, 'push');
            app.FixedImageButton.ButtonPushedFcn = createCallbackFcn(app, @FixedImageButtonPushed, true);
            app.FixedImageButton.Text = ' Fixed Image';
            app.MovingImageButton = uibutton(app.Panel, 'push');
            app.MovingImageButton.ButtonPushedFcn = createCallbackFcn(app, @MovingImageButtonPushed, true);
            app.MovingImageButton.Text = 'Moving Image';
            app.MovingListBoxLabel = uilabel(app.Panel);
            app.MovingListBoxLabel.Text = 'Moving ';
            app.MovingListBox = uilistbox(app.Panel);
            app.MovingListBox.ValueChangedFcn = createCallbackFcn(app, @MovingListBoxValueChanged, true);
            app.FixedSliceSliderLabel = uilabel(app.Panel);
            app.FixedSliceSliderLabel.Text = 'Fixed Slice';
            app.FixedSliceSlider = uislider(app.Panel);
            app.FixedSliceSlider.Limits = [1 100];
            app.FixedSliceSlider.ValueChangingFcn = createCallbackFcn(app, @FixedSliceSliderValueChanging, true);
            app.FixedSliceSlider.Value = 1;
            app.MovingSliceSliderLabel = uilabel(app.Panel);
            app.MovingSliceSliderLabel.Text = 'Moving Slice';
            app.MovingSliceSlider = uislider(app.Panel);
            app.MovingSliceSlider.Limits = [1 100];
            app.MovingSliceSlider.ValueChangingFcn = createCallbackFcn(app, @MovingSliceSliderValueChanging, true);
            app.MovingSliceSlider.Value = 1;
            app.RegisterButton = uibutton(app.Panel, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterButtonPushed, true);
            app.RegisterButton.Text = 'Register';
            app.RegisterButton.Icon = fullfile(pathToMLAPP, 'icons', 'register.png');
            app.SendRegisteredImagetoSegmenterButton = uibutton(app.Panel, 'push');
            app.SendRegisteredImagetoSegmenterButton.ButtonPushedFcn = createCallbackFcn(app, @SendRegisteredImagetoSegmenterButtonPushed, true);
            app.SendRegisteredImagetoSegmenterButton.Text = 'Send Registered Image to Segmenter';
            app.SendRegisteredImagetoSegmenterButton.Icon = fullfile(pathToMLAPP, 'icons', 'send.png');
            app.LoadButton = uibutton(app.Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.LoadButton.Icon = fullfile(pathToMLAPP, 'icons', 'import.png');
            app.LoadButton_2 = uibutton(app.Panel, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.LoadButton_2.Icon = fullfile(pathToMLAPP, 'icons', 'import.png');
            app.ShowPairButton = uibutton(app.Panel, 'push');
            app.ShowPairButton.ButtonPushedFcn = createCallbackFcn(app, @ShowPairButtonPushed, true);
            app.ShowPairButton.Text = 'Show Pair';
            app.ShowPairButton.Icon = fullfile(pathToMLAPP, 'icons', 'pair.png');
            app.LocationEditField_2Label = uilabel(app.Panel);
            app.LocationEditField_2Label.Text = 'Location';
            app.LocationEditField_2 = uieditfield(app.Panel, 'numeric');
            app.LocationEditField_3Label = uilabel(app.Panel);
            app.LocationEditField_3Label.Text = 'Location';
            app.LocationEditField_3 = uieditfield(app.Panel, 'numeric');
            app.CoveredDepthEditFieldLabel = uilabel(app.Panel);
            app.CoveredDepthEditFieldLabel.Text = 'Covered Depth';
            app.CoveredDepthEditField = uieditfield(app.Panel, 'numeric');
            app.CoveredDepthEditField_2Label = uilabel(app.Panel);
            app.CoveredDepthEditField_2Label.Text = 'Covered Depth';
            app.CoveredDepthEditField_2 = uieditfield(app.Panel, 'numeric');
            app.FilterButton = uibutton(app.Panel, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter ';
            app.StatusEditFieldLabel = uilabel(app.Panel);
            app.StatusEditFieldLabel.Text = 'Status';
            app.RegisterStatusEditField = uieditfield(app.Panel, 'text');
            app.DrawROIButton_2 = uibutton(app.Panel, 'push');
            app.DrawROIButton_2.ButtonPushedFcn = createCallbackFcn(app, @DrawROIButton_2Pushed, true);
            app.DrawROIButton_2.Text = 'Draw ROI';
            app.DrawROIButton_3 = uibutton(app.Panel, 'push');
            app.DrawROIButton_3.ButtonPushedFcn = createCallbackFcn(app, @DrawROIButton_3Pushed, true);
            app.DrawROIButton_3.Text = 'Draw ROI';
            app.FixedSegmentButton = uibutton(app.Panel, 'push');
            app.FixedSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @FixedSegmentButtonPushed, true);
            app.FixedSegmentButton.Text = 'Fixed Segment';
            app.MovingSegmentButton = uibutton(app.Panel, 'push');
            app.MovingSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @MovingSegmentButtonPushed, true);
            app.MovingSegmentButton.Text = 'Moving Segment';
            app.SegmentGuidedRegisterButton = uibutton(app.Panel, 'push');
            app.SegmentGuidedRegisterButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentGuidedRegisterButtonPushed, true);
            app.SegmentGuidedRegisterButton.Text = 'Segment Guided Register';
            app.SegmentGuidedRegisterButton.Icon = fullfile(pathToMLAPP, 'icons', 'register.png');
            app.SegmenterTab = uitab(app.TabGroup);
            app.SegmenterTab.Title = 'Segmenter';
            app.Segmenter = uipanel(app.SegmenterTab);
            app.Segmenter.Title = 'Segmenter';
            app.UIAxes14 = uiaxes(app.Segmenter);
            title(app.UIAxes14, 'Title')
            xlabel(app.UIAxes14, 'X')
            ylabel(app.UIAxes14, 'Y')
            zlabel(app.UIAxes14, 'Z')
            app.UIAxes14_2 = uiaxes(app.Segmenter);
            title(app.UIAxes14_2, 'Title')
            xlabel(app.UIAxes14_2, 'X')
            ylabel(app.UIAxes14_2, 'Y')
            zlabel(app.UIAxes14_2, 'Z')
            app.SegmentButton = uibutton(app.Segmenter, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentButtonPushed, true);
            app.SegmentButton.Text = 'Segment';
            app.SegmentButton.Icon = fullfile(pathToMLAPP, 'icons', 'segment.png');
            app.DrawCortexGroundTruthButton = uibutton(app.Segmenter, 'push');
            app.DrawCortexGroundTruthButton.ButtonPushedFcn = createCallbackFcn(app, @DrawCortexGroundTruthButtonPushed, true);
            app.DrawCortexGroundTruthButton.Enable = 'off';
            app.DrawCortexGroundTruthButton.Text = 'Draw Cortex Ground Truth';
            app.JaccardCortexEditFieldLabel = uilabel(app.Segmenter);
            app.JaccardCortexEditFieldLabel.Text = 'Jaccard Cortex';
            app.JaccardCortexEditField = uieditfield(app.Segmenter, 'numeric');
            app.DICECortexEditFieldLabel = uilabel(app.Segmenter);
            app.DICECortexEditFieldLabel.Text = 'DICE Cortex';
            app.DICECortexEditField = uieditfield(app.Segmenter, 'numeric');
            app.JaccardMedullaEditFieldLabel = uilabel(app.Segmenter);
            app.JaccardMedullaEditFieldLabel.Text = 'Jaccard Medulla';
            app.JaccardMedullaEditField = uieditfield(app.Segmenter, 'numeric');
            app.DICEMedullaEditFieldLabel = uilabel(app.Segmenter);
            app.DICEMedullaEditFieldLabel.Text = 'DICE Medulla';
            app.DICEMedullaEditField = uieditfield(app.Segmenter, 'numeric');
            app.CalculateSimilarityCoefficientsButton = uibutton(app.Segmenter, 'push');
            app.CalculateSimilarityCoefficientsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateSimilarityCoefficientsButtonPushed, true);
            app.CalculateSimilarityCoefficientsButton.Text = 'Calculate Similarity Coefficients';
            app.FitParameterSettingsTab = uitab(app.TabGroup);
            app.FitParameterSettingsTab.Title = 'Fit Parameter Settings';
            app.ADCSettings = uipanel(app.FitParameterSettingsTab);
            app.ADCSettings.Title = 'ADC(Monoexponential)';
            app.StartPointEditFieldLabel = uilabel(app.ADCSettings);
            app.StartPointEditFieldLabel.Text = 'Start Point';
            app.StartPointEditFieldADC = uieditfield(app.ADCSettings, 'numeric');
            app.LowerLimitEditFieldLabel = uilabel(app.ADCSettings);
            app.LowerLimitEditFieldLabel.Text = 'Lower Limit';
            app.LowerLimitEditFieldADC = uieditfield(app.ADCSettings, 'numeric');
            app.UpperLimitEditFieldLabel = uilabel(app.ADCSettings);
            app.UpperLimitEditFieldLabel.Text = 'Upper Limit';
            app.UpperLimitEditFieldADC = uieditfield(app.ADCSettings, 'numeric');
            app.ResetButtonADC = uibutton(app.ADCSettings, 'push');
            app.ResetButtonADC.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonADCPushed, true);
            app.ResetButtonADC.Text = 'Reset';
            app.IVIMSettings = uipanel(app.FitParameterSettingsTab);
            app.IVIMSettings.Title = 'IVIM';
            app.StartPointEditFieldLabel_2 = uilabel(app.IVIMSettings);
            app.StartPointEditFieldLabel_2.Text = 'Start Point';
            app.StartPointEditField_Dt = uieditfield(app.IVIMSettings, 'numeric');
            app.UpperLimitEditFieldLabel_2 = uilabel(app.IVIMSettings);
            app.UpperLimitEditFieldLabel_2.Text = 'Upper Limit';
            app.UpperLimitEditField_Dt = uieditfield(app.IVIMSettings, 'numeric');
            app.BThresholdEditFieldLabel = uilabel(app.IVIMSettings);
            app.BThresholdEditFieldLabel.Text = 'B-Threshold';
            app.BThresholdEditField = uieditfield(app.IVIMSettings, 'numeric');
            app.LowerLimitEditFieldLabel_4 = uilabel(app.IVIMSettings);
            app.LowerLimitEditFieldLabel_4.Text = 'Lower Limit';
            app.LowerLimitEditField_f = uieditfield(app.IVIMSettings, 'numeric');
            app.UpperLimitEditFieldLabel_4 = uilabel(app.IVIMSettings);
            app.UpperLimitEditFieldLabel_4.Text = 'Upper Limit';
            app.UpperLimitEditField_f = uieditfield(app.IVIMSettings, 'numeric');
            app.LowerLimitEditFieldLabel_2 = uilabel(app.IVIMSettings);
            app.LowerLimitEditFieldLabel_2.Text = 'Lower Limit';
            app.LowerLimitEditField_Dt = uieditfield(app.IVIMSettings, 'numeric');
            app.StartPointEditFieldLabel_3 = uilabel(app.IVIMSettings);
            app.StartPointEditFieldLabel_3.Text = 'Start Point';
            app.StartPointEditField_Dp = uieditfield(app.IVIMSettings, 'numeric');
            app.UpperLimitEditFieldLabel_3 = uilabel(app.IVIMSettings);
            app.UpperLimitEditFieldLabel_3.Text = 'Upper Limit';
            app.UpperLimitEditField_Dp = uieditfield(app.IVIMSettings, 'numeric');
            app.LowerLimitEditFieldLabel_3 = uilabel(app.IVIMSettings);
            app.LowerLimitEditFieldLabel_3.Text = 'Lower Limit';
            app.LowerLimitEditField_Dp = uieditfield(app.IVIMSettings, 'numeric');
            app.StartPointEditFieldLabel_4 = uilabel(app.IVIMSettings);
            app.StartPointEditFieldLabel_4.Text = 'Start Point';
            app.StartPointEditField_f = uieditfield(app.IVIMSettings, 'numeric');
            app.ResetButtonIVIM = uibutton(app.IVIMSettings, 'push');
            app.ResetButtonIVIM.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonIVIMPushed, true);
            app.ResetButtonIVIM.Text = 'Reset';
            app.EditFieldIVIM_Dt = uieditfield(app.IVIMSettings, 'text');
            app.EditFieldIVIM_Dt.Editable = 'off';
            app.EditFieldIVIM_Dp = uieditfield(app.IVIMSettings, 'text');
            app.EditFieldIVIM_Dp.Editable = 'off';
            app.EditFieldIVIM_f = uieditfield(app.IVIMSettings, 'text');
            app.EditFieldIVIM_f.Editable = 'off';
            app.TriExpSettings = uipanel(app.FitParameterSettingsTab);
            app.TriExpSettings.Title = 'Tri-Exponential';
            app.UpperLimitEditFieldLabel_5 = uilabel(app.TriExpSettings);
            app.UpperLimitEditFieldLabel_5.Text = 'Upper Limit';
            app.UpperLimitEditField_D1 = uieditfield(app.TriExpSettings, 'numeric');
            app.StartPointEditFieldLabel_5 = uilabel(app.TriExpSettings);
            app.StartPointEditFieldLabel_5.Text = 'Start Point';
            app.StartPointEditField_D1 = uieditfield(app.TriExpSettings, 'numeric');
            app.StartPointEditFieldLabel_9 = uilabel(app.TriExpSettings);
            app.StartPointEditFieldLabel_9.Text = 'Start Point';
            app.StartPointEditField_f2 = uieditfield(app.TriExpSettings, 'numeric');
            app.LowerLimitEditFieldLabel_5 = uilabel(app.TriExpSettings);
            app.LowerLimitEditFieldLabel_5.Text = 'Lower Limit';
            app.LowerLimitEditField_D1 = uieditfield(app.TriExpSettings, 'numeric');
            app.StartPointEditFieldLabel_6 = uilabel(app.TriExpSettings);
            app.StartPointEditFieldLabel_6.Text = 'Start Point';
            app.StartPointEditField_D2 = uieditfield(app.TriExpSettings, 'numeric');
            app.UpperLimitEditFieldLabel_6 = uilabel(app.TriExpSettings);
            app.UpperLimitEditFieldLabel_6.Text = 'Upper Limit';
            app.UpperLimitEditField_D2 = uieditfield(app.TriExpSettings, 'numeric');
            app.LowerLimitEditFieldLabel_6 = uilabel(app.TriExpSettings);
            app.LowerLimitEditFieldLabel_6.Text = 'Lower Limit';
            app.LowerLimitEditField_D2 = uieditfield(app.TriExpSettings, 'numeric');
            app.StartPointEditFieldLabel_7 = uilabel(app.TriExpSettings);
            app.StartPointEditFieldLabel_7.Text = 'Start Point';
            app.StartPointEditField_D3 = uieditfield(app.TriExpSettings, 'numeric');
            app.UpperLimitEditFieldLabel_7 = uilabel(app.TriExpSettings);
            app.UpperLimitEditFieldLabel_7.Text = 'Upper Limit';
            app.UpperLimitEditField_D3 = uieditfield(app.TriExpSettings, 'numeric');
            app.LowerLimitEditFieldLabel_7 = uilabel(app.TriExpSettings);
            app.LowerLimitEditFieldLabel_7.Text = 'Lower Limit';
            app.LowerLimitEditField_D3 = uieditfield(app.TriExpSettings, 'numeric');
            app.StartPointEditFieldLabel_8 = uilabel(app.TriExpSettings);
            app.StartPointEditFieldLabel_8.Text = 'Start Point';
            app.StartPointEditField_f1 = uieditfield(app.TriExpSettings, 'numeric');
            app.UpperLimitEditFieldLabel_8 = uilabel(app.TriExpSettings);
            app.UpperLimitEditFieldLabel_8.Text = 'Upper Limit';
            app.UpperLimitEditField_f1 = uieditfield(app.TriExpSettings, 'numeric');
            app.LowerLimitEditFieldLabel_8 = uilabel(app.TriExpSettings);
            app.LowerLimitEditFieldLabel_8.Text = 'Lower Limit';
            app.LowerLimitEditField_f1 = uieditfield(app.TriExpSettings, 'numeric');
            app.UpperLimitEditFieldLabel_9 = uilabel(app.TriExpSettings);
            app.UpperLimitEditFieldLabel_9.Text = 'Upper Limit';
            app.UpperLimitEditField_f2 = uieditfield(app.TriExpSettings, 'numeric');
            app.LowerLimitEditFieldLabel_9 = uilabel(app.TriExpSettings);
            app.LowerLimitEditFieldLabel_9.Text = 'Lower Limit';
            app.LowerLimitEditField_f2 = uieditfield(app.TriExpSettings, 'numeric');
            app.ResetButtonTri = uibutton(app.TriExpSettings, 'push');
            app.ResetButtonTri.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonTriPushed, true);
            app.ResetButtonTri.Text = 'Reset';
            app.EditFieldTri_Dfast = uieditfield(app.TriExpSettings, 'text');
            app.EditFieldTri_Dfast.Editable = 'off';
            app.EditFieldTri_Dinter = uieditfield(app.TriExpSettings, 'text');
            app.EditFieldTri_Dinter.Editable = 'off';
            app.EditFieldTri_Dslow = uieditfield(app.TriExpSettings, 'text');
            app.EditFieldTri_Dslow.Editable = 'off';
            app.EditFieldTri_f1 = uieditfield(app.TriExpSettings, 'text');
            app.EditFieldTri_f1.Editable = 'off';
            app.EditFieldTri_f2 = uieditfield(app.TriExpSettings, 'text');
            app.EditFieldTri_f2.Editable = 'off';
            % Show the figure after all components are created
            app.DiffusonCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Diffusion_Calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DiffusonCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DiffusonCalculatorUIFigure)
        end
    end
end